classdef P2_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Seal6Button  matlab.ui.control.Button
        Seal5Button  matlab.ui.control.Button
        Seal4Button  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Seal4Button
        function Seal4ButtonPushed(app, event)
            % Tiempo and crecimiento_nivel_mar data
            tiempo = 2012:0.5:2021;
            crecimiento_nivel_mar = [3.6, 3.4, 4, 3.8, 4.7, 4.5, 5.6, 5.4, 6.2, 6, 7.5, 7.3, 8.3, 8.1, 9, 8.8, 9.4, 9.2, 10];

            plot(app.UIAxes, tiempo, crecimiento_nivel_mar);
            title(app.UIAxes, 'Elevación del Nivel del Mar');
            xlabel(app.UIAxes, 'Año');
            ylabel(app.UIAxes, 'Aumento nivel del Mar (cm)');
        end

        % Button pushed function: Seal5Button
        function Seal5ButtonPushed(app, event)
            % cantidad_luz_solar and velocidad_respiracion data
            cantidad_luz_solar = 150:25:1050;
            velocidad_respiracion = [0, 0.12, 0.35, 0.6, 0.89, 1.23, 1.61, 2.04, 2.52, 3.05, 3.64, 4.29, 5.01, 5.81, 6.69, 7.66, 8.71, 9.87, 11.13, 12.52, 13.92, 15.48, 17.22, 19.17, 21.38, 23.89, 26.77, 30.11, 34, 38.55, 43.88, 50.09, 57.34, 65.82, 80, 80, 80];

            plot(app.UIAxes, cantidad_luz_solar, velocidad_respiracion);
            title(app.UIAxes, 'Crecimiento de las Plantas');
            xlabel(app.UIAxes, 'Cantidad de Luz Solar (umol/co2/m2/s)x10');
            ylabel(app.UIAxes, 'Velocidad de respiracion (umol/m2/s)');
        end

        % Button pushed function: Seal6Button
        function Seal6ButtonPushed(app, event)
            % Temperatura and humedad_aire data
            temperatura = 40:1:80;
            humedad_aire = [100, 98.5, 96.5, 94.4, 92.56, 90.5, 88.5 , 85.3, 83.15, 79.3 , 76.3, 75.9, 73.3, 71.3 , 69.15 , 67.3 ,64 , 61.4 , 59.89, 57.7, 55, 52.39, 49.79, 47.19, 45.59, 42.99, 40.39, 38.79, 36.19, 35.59, 34.99, 33.39, 31.79, 29.19, 27.8, 26.6 , 25.5, 23.9, 22.5, 21.9, 20];
            
            % Plot the third set of data
            plot(app.UIAxes, temperatura, humedad_aire);
            title(app.UIAxes, 'Formación de Nubes en el Cielo');
            xlabel(app.UIAxes, 'Temperatura (°F)');
            ylabel(app.UIAxes, 'Humedad relativa del aire (%)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Seal4Button = uibutton(app.UIFigure, 'push');
            app.Seal4Button.ButtonPushedFcn = createCallbackFcn(app, @Seal4ButtonPushed, true);
            app.Seal4Button.Text = 'Señal 4';
            app.Seal5Button = uibutton(app.UIFigure, 'push');
            app.Seal5Button.ButtonPushedFcn = createCallbackFcn(app, @Seal5ButtonPushed, true);
            app.Seal5Button.Text = 'Señal 5';
            app.Seal6Button = uibutton(app.UIFigure, 'push');
            app.Seal6Button.ButtonPushedFcn = createCallbackFcn(app, @Seal6ButtonPushed, true);
            app.Seal6Button.Text = 'Señal 6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end