classdef P2_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ymaxEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        yminEditFieldLabel             matlab.ui.control.Label
        xmaxEditField                  matlab.ui.control.NumericEditField
        xmaxEditFieldLabel             matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        xminEditFieldLabel             matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        fxEditField                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        IngresalasealfxagraficarLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
% Obtener el polinomio como una cadena de caracteres
pchar = app.fxEditField.Value;

% Convertir el polinomio a una variable simbólica
psym = str2sym(pchar);

% Convertir el polinomio a un vector de coeficientes
pvec = sym2poly(psym);

% Obtener los límites para el gráfico
xmin = app.xminEditField.Value;
xmax = app.xmaxEditField.Value;
ymin = app.yminEditField.Value;
ymax = app.ymaxEditField.Value;

% Evaluación del polinomio
x = linspace(xmin, xmax, 100);
y = polyval(pvec, x);

% Gráfico
plot(app.UIAxes, x, y, 'b-');

% Limites
if isinf(ymin)
    ymin = min(y);
end
if isinf(ymax)
    ymax = max(y);
end
app.UIAxes.XLim = [xmin, xmax];
app.UIAxes.YLim = [ymin, ymax];

% Cuadrícula
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IngresalasealfxagraficarLabel = uilabel(app.UIFigure);
            app.IngresalasealfxagraficarLabel.Text = 'Ingresa la señal f(x) a graficar:';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = '(x-1)*(x-2)^(x-3)';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Value = -Inf;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end