classdef Practica3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DivisionesSpinner           matlab.ui.control.Spinner
        DivisionesSpinnerLabel      matlab.ui.control.Label
        RepeticionesSpinner         matlab.ui.control.Spinner
        RepeticionesSpinnerLabel    matlab.ui.control.Label
        TipoSealEditField           matlab.ui.control.EditField
        TipoSealEditFieldLabel      matlab.ui.control.Label
        DesplazamientoSpinner       matlab.ui.control.Spinner
        DesplazamientoSpinnerLabel  matlab.ui.control.Label
        FrecuenciademuestreoporsegundoSpinner  matlab.ui.control.Spinner
        FrecuenciademuestreoporsegundoSpinnerLabel  matlab.ui.control.Label
        AmplitudSpinner             matlab.ui.control.Spinner
        AmplitudSpinnerLabel        matlab.ui.control.Label
        FrecuenciaSpinner           matlab.ui.control.Spinner
        FrecuenciaSpinnerLabel      matlab.ui.control.Label
        TiempodelasealLabel         matlab.ui.control.Label
        GeneradordesealesLabel      matlab.ui.control.Label
        tfEditField                 matlab.ui.control.NumericEditField
        tfEditFieldLabel            matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        DropDownSenales             matlab.ui.control.DropDown
        SealLabel                   matlab.ui.control.Label
        ButtonGenerar               matlab.ui.control.Button
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signalPlot = []; % Para almacenar la última grafica generada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGenerar, UIAxes
        function ButtonGenerarPushed(app, event)
            f=app.FrecuenciaSpinner.Value; %Se obtiene la frecuencia del slider
            ampli=app.AmplitudSpinner.Value; %Se obtiene la amplitud del slider
            % Crear las señales continuas o discretas
            x=app.toEditField.Value:0.01:app.tfEditField.Value;
            xn=app.toEditField.Value:app.FrecuenciademuestreoporsegundoSpinner.Value:app.tfEditField.Value;

            switch app.DropDownSenales.Value

                    case 'Pulso rectangular'
                        
                        %Pulso rectangular continuo
                        y = ampli * (abs(x+ app.DesplazamientoSpinner.Value) < f/2);

                        %Pulso rectangular discreto
                        yn = ampli * (abs(xn + app.DesplazamientoSpinner.Value) < f/2);

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                        title(app.UIAxes,'Señal Continua f(t) - Pulso rectangular');
                        xlabel(app.UIAxes,'Tiempo (s)');
                        ylabel(app.UIAxes,'Amplitud (m)');
                        
                        stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                        title(app.UIAxes2,'Señal Discreta f[t] - Pulso rectangular');
                        xlabel(app.UIAxes2,'Tiempo (s)');
                        ylabel(app.UIAxes2,'Amplitud (m)');

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * (abs(xLimitado + app.DesplazamientoSpinner.Value) < f/2);
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');


                    case 'Escalón unitario'                        
                        % Definir la señal continua f(t)
                        y = ampli * (x + app.DesplazamientoSpinner.Value >= 0);
                        
                        % Definir la señal discreta f[t]
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value >= 0);
                        
                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                        title(app.UIAxes,'Señal Continua f(t) - Escalón Unitario');
                        xlabel(app.UIAxes,'Tiempo (s)');
                        ylabel(app.UIAxes,'Amplitud (m)');
                        
                        stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                        title(app.UIAxes2,'Señal Discreta f[t] - Escalón Unitario');
                        xlabel(app.UIAxes2,'Tiempo (s)');
                        ylabel(app.UIAxes2,'Amplitud (m)');

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * (xLimitado + app.DesplazamientoSpinner.Value >= 0);
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');
                        
                    case 'Impulso unitario'
                        y = ampli * (x + app.DesplazamientoSpinner.Value== 0);
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value== 0);

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Impulso Unitario'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Impulso Unitario');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida - Impulso Unitario');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud');

                        % Limitar la señal en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * (xLimitado + app.DesplazamientoSpinner.Value == 0);
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo - Impulso Unitario');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud');
                    

                    case 'Pulso triangular'
                        
                        %Pulso triangular
                        y = (1 - abs(x + app.DesplazamientoSpinner.Value)/f) .* (abs(x + app.DesplazamientoSpinner.Value) <= f);
                        yn = (1 - abs(xn + app.DesplazamientoSpinner.Value)/f) .* (abs(xn + app.DesplazamientoSpinner.Value) <= f);
                        
                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Pulso Triangular'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Pulso Triangular');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida - Pulso triangular');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = (1 - abs(xLimitado + app.DesplazamientoSpinner.Value)/f) .* (abs(xLimitado + app.DesplazamientoSpinner.Value) <= f);
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo - Pulso triangular');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');

                    case 'Rampa'

                        y = ampli * (x + app.DesplazamientoSpinner.Value).* (x + app.DesplazamientoSpinner.Value >= 0);
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value) .* (xn + app.DesplazamientoSpinner.Value >= 0);

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');
                        
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Rampa'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Rampa');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida - Rampa');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * (xLimitado + app.DesplazamientoSpinner.Value) .* (xLimitado + app.DesplazamientoSpinner.Value >= 0);
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo - Rampa');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');

                    case 'Senoidal'
                        y = ampli * sin(2*pi*f*(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * sin(2*pi*f*(xn + app.DesplazamientoSpinner.Value));

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Senoidal'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Senoidal');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida - Senoidal');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * sin(2 * pi * f * (xLimitado + app.DesplazamientoSpinner.Value));
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo - Senoidal');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');

                    case 'Senoidal compleja'
                        y = ampli * exp(1i*2*pi*f*(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(1i*2*pi*f*(xn + app.DesplazamientoSpinner.Value));

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Senoidal compleja'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Senoidal compleja');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, real(y), 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Parte Real de la Señal Repetida - Senoidal Compleja');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * exp(1i * 2 * pi * f * (xLimitado + app.DesplazamientoSpinner.Value));
                        plot(app.UIAxes6, xLimitado, real(yLimitado), 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Parte Real de la Señal Limitada en el Tiempo - Senoidal Compleja');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');

                    case 'Exponencial'
                        y = ampli * exp(-(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(-(xn+app.DesplazamientoSpinner.Value));

                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Exponencial'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Exponencial');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, y, 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Señal Repetida - Exponencial');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * exp(-(xLimitado + app.DesplazamientoSpinner.Value));
                        plot(app.UIAxes6, xLimitado, yLimitado, 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Señal Limitada en el Tiempo - Exponencial');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');

                    case 'Exponencial compleja'
                        y = ampli * exp(1i*2*pi*f*(x + app.DesplazamientoSpinner.Value)) .* exp(-(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(1i*2*pi*f*(xn + app.DesplazamientoSpinner.Value)) .* exp(-(xn + app.DesplazamientoSpinner.Value));
                        
                        % Calcular la componente par e impar
                        y_par = 0.5 * (y + fliplr(y));
                        y_impar = 0.5 * (y - fliplr(y));

                        % Graficar la componente par
                        plot(app.UIAxes3, x, y_par, 'g', 'LineWidth', 2);
                        title(app.UIAxes3, 'Componente Par de f(t)');
                        xlabel(app.UIAxes3, 'Tiempo (s)');
                        ylabel(app.UIAxes3, 'Amplitud (m)');

                        % Graficar la componente impar
                        plot(app.UIAxes4, x, y_impar, 'm', 'LineWidth', 2);
                        title(app.UIAxes4, 'Componente Impar de f(t)');
                        xlabel(app.UIAxes4, 'Tiempo (s)');
                        ylabel(app.UIAxes4, 'Amplitud (m)');                        

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Exponencial compleja'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Exponencial compleja');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                        % Repetir la señal en UIAxes3
                        numRepeticiones = app.RepeticionesSpinner.Value;
                        periodo = app.tfEditField.Value - app.toEditField.Value;
                        for i = 0:numRepeticiones-1
                            plot(app.UIAxes5, x + i * periodo, real(y), 'g', 'LineWidth', 2);
                            hold(app.UIAxes5, 'on');
                        end
                        hold(app.UIAxes5, 'off');
                        title(app.UIAxes5, 'Parte Real de la Señal Repetida - Exponencial Compleja');
                        xlabel(app.UIAxes5, 'Tiempo (s)');
                        ylabel(app.UIAxes5, 'Amplitud (m)');

                        % Limitar la señal en el tiempo en UIAxes4
                        limitarTiempo = (1/(app.DivisionesSpinner.Value));
                        nuevoTo = app.toEditField.Value + (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        nuevoTf = app.tfEditField.Value - (app.tfEditField.Value - app.toEditField.Value) * (1 - limitarTiempo) / 2;
                        xLimitado = nuevoTo:0.01:nuevoTf;
                        yLimitado = ampli * exp((1i*2*pi*f - 1) * (xLimitado + app.DesplazamientoSpinner.Value));
                        plot(app.UIAxes6, xLimitado, real(yLimitado), 'm', 'LineWidth', 2);
                        title(app.UIAxes6, 'Parte Real de la Señal Limitada en el Tiempo - Exponencial Compleja');
                        xlabel(app.UIAxes6, 'Tiempo (s)');
                        ylabel(app.UIAxes6, 'Amplitud (m)');
            end

            % Calcular la energía de la señal
            energia = trapz(x, abs(y).^2);

            % Calcular la potencia promedio
            T = max(x) - min(x); % Esto es un aproximado, para señales de duración finita
            potencia = (1/(2*T)) * energia;

            % Determinar el tipo de señal y mostrar en un cuadro de texto
            if isfinite(energia) && energia > 0 && potencia <1
                % Señal de energía
                app.TipoSealEditField.Value= 'Señal de Energía';
            elseif isfinite(potencia) && potencia > 0
                % Señal de potencia
                app.TipoSealEditField.Value= 'Señal de Potencia';
            else
                % Ninguna de ellas
                app.TipoSealEditField.Value= 'Ni Señal de Energía ni de Potencia';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ButtonGenerar = uibutton(app.UIFigure, 'push');
            app.ButtonGenerar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.ButtonGenerar.Text = 'Generar señal';
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Tipo de señal';
            app.DropDownSenales = uidropdown(app.UIFigure);
            app.DropDownSenales.Items = {'Escalón unitario', 'Impulso unitario', 'Rampa', 'Senoidal', 'Senoidal compleja', 'Exponencial', 'Exponencial compleja', 'Pulso triangular', 'Pulso rectangular'};
            app.DropDownSenales.Value = 'Escalón unitario';
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = -10;
            app.tfEditFieldLabel = uilabel(app.UIFigure);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.UIFigure, 'numeric');
            app.tfEditField.Value = 10;
            app.GeneradordesealesLabel = uilabel(app.UIFigure);
            app.GeneradordesealesLabel.Text = 'Generador de señales';
            app.TiempodelasealLabel = uilabel(app.UIFigure);
            app.TiempodelasealLabel.Text = 'Tiempo de la señal';
            app.FrecuenciaSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciaSpinnerLabel.Text = 'Frecuencia';
            app.FrecuenciaSpinner = uispinner(app.UIFigure);
            app.FrecuenciaSpinner.Value = 1;
            app.AmplitudSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudSpinnerLabel.Text = 'Amplitud';
            app.AmplitudSpinner = uispinner(app.UIFigure);
            app.AmplitudSpinner.Value = 5;
            app.FrecuenciademuestreoporsegundoSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinnerLabel.Text = 'Frecuencia de muestreo por segundo';
            app.FrecuenciademuestreoporsegundoSpinner = uispinner(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinner.Limits = [0 1];
            app.FrecuenciademuestreoporsegundoSpinner.Value = 0.1;
            app.DesplazamientoSpinnerLabel = uilabel(app.UIFigure);
            app.DesplazamientoSpinnerLabel.Text = 'Desplazamiento';
            app.DesplazamientoSpinner = uispinner(app.UIFigure);
            app.TipoSealEditFieldLabel = uilabel(app.UIFigure);
            app.TipoSealEditFieldLabel.Text = 'Tipo Señal';
            app.TipoSealEditField = uieditfield(app.UIFigure, 'text');
            app.RepeticionesSpinnerLabel = uilabel(app.UIFigure);
            app.RepeticionesSpinnerLabel.Text = 'Repeticiones';
            app.RepeticionesSpinner = uispinner(app.UIFigure);
            app.RepeticionesSpinner.Limits = [1 Inf];
            app.RepeticionesSpinner.Value = 1;
            app.DivisionesSpinnerLabel = uilabel(app.UIFigure);
            app.DivisionesSpinnerLabel.Text = 'Divisiones';
            app.DivisionesSpinner = uispinner(app.UIFigure);
            app.DivisionesSpinner.Limits = [1 Inf];
            app.DivisionesSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end