classdef practica811 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        FactorcorrimientoEditField      matlab.ui.control.NumericEditField
        FactordeescalaEditField         matlab.ui.control.NumericEditField
        FactordeescalaEditFieldLabel    matlab.ui.control.Label
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        MultiplicacinButton             matlab.ui.control.RadioButton
        SumaButton                      matlab.ui.control.RadioButton
        IntegracinButton                matlab.ui.control.RadioButton
        DiferenciacinButton             matlab.ui.control.RadioButton
        EscalamientodeamplitudButton    matlab.ui.control.RadioButton
        OperarenlasealLabel             matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        DiscretaButton                  matlab.ui.control.RadioButton
        ContinuaButton                  matlab.ui.control.RadioButton
        tLabel                          matlab.ui.control.Label
        y1txLabel                       matlab.ui.control.Label
        NumEditField                    matlab.ui.control.NumericEditField
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        CorrimientodetiempoButton       matlab.ui.control.RadioButton
        ReflexionButton                 matlab.ui.control.RadioButton
        EscalamientodetiempoButton      matlab.ui.control.RadioButton
        TipodeoperacionLabel            matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        IndependienteButton             matlab.ui.control.RadioButton
        DependienteButton               matlab.ui.control.RadioButton
        RealizaroperacionsobrelavariableLabel  matlab.ui.control.Label
        OPERACIONESLabel                matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        AlgoritmoButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        FuncionButton                   matlab.ui.control.Button
        CorrelacinLabel                 matlab.ui.control.Label
        CorrimientoEditField            matlab.ui.control.NumericEditField
        CorrimientoEditFieldLabel       matlab.ui.control.Label
        Generarseal2Button              matlab.ui.control.Button
        Generarseal1Button              matlab.ui.control.Button
        ModoButtonGroup                 matlab.ui.container.ButtonGroup
        CorrelacionButton               matlab.ui.control.RadioButton
        ConvolucionButton               matlab.ui.control.RadioButton
        GeneradorButton                 matlab.ui.control.RadioButton
        CompararButton                  matlab.ui.control.Button
        TejecucionalgortimoLabel        matlab.ui.control.Label
        TejecucionFuncLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        AlgoritmoconvButton             matlab.ui.control.Button
        FuncionconvButton               matlab.ui.control.Button
        TipodesealLabel                 matlab.ui.control.Label
        ConvolucinLabel                 matlab.ui.control.Label
        Seal2Button                     matlab.ui.control.Button
        Seal1Button                     matlab.ui.control.Button
        PoEButton                       matlab.ui.control.Button
        ButtonGenerar                   matlab.ui.control.Button
        DescomponerdiscretaButton       matlab.ui.control.Button
        DescomponercontinuaButton       matlab.ui.control.Button
        alphaSwitch                     matlab.ui.control.Switch
        DropDownSenales                 matlab.ui.control.DropDown
        SealLabel                       matlab.ui.control.Label
        ParteSwitch                     matlab.ui.control.Switch
        RfEditField                     matlab.ui.control.NumericEditField
        RfEditFieldLabel                matlab.ui.control.Label
        RoEditField                     matlab.ui.control.NumericEditField
        RoEditFieldLabel                matlab.ui.control.Label
        TazamuestreoEditField           matlab.ui.control.NumericEditField
        TazamuestreoEditFieldLabel      matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AperiodicaButton                matlab.ui.control.RadioButton
        PeriodicaButton                 matlab.ui.control.RadioButton
        PeriodosdiscretaEditField       matlab.ui.control.NumericEditField
        PeriodosdiscretaEditFieldLabel  matlab.ui.control.Label
        PeriodoscontinuaEditField       matlab.ui.control.NumericEditField
        PeriodoscontinuaEditFieldLabel  matlab.ui.control.Label
        FrecuenciaSlider                matlab.ui.control.Slider
        FrecuenciaSliderLabel           matlab.ui.control.Label
        AmplitudSlider                  matlab.ui.control.Slider
        AmplitudSliderLabel             matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        signalPlot = []; % Para almacenar la última grafica generada
        y1continua=[];
        y1discreta=[];
        y2continua=[];
        y2discreta=[];% Description
    end
    
    properties (Access = public)
        senaly=[];
        senalg=[];
        tituloy=[];
        titulog=[];% Description
        xdeg=[];
        xdey=[];% Description
        scorr=[]; % Description
    end
    
    methods (Access = private)
        
        function plotear(app, axes, x, y, ro, rf, titulo, continuaDiscreta, periodicidad)        

            if continuaDiscreta == 'continua'
                plot(axes, x, y, 'b', 'LineWidth', 2);
                title(axes,titulo);
                xlabel(axes,'Tiempo Continuo');
            elseif continuaDiscreta == 'discreta'
                stem(axes, x, y, 'r', 'LineWidth', 2);
                title(axes,titulo);
                xlabel(axes,'Indice discreto');
            end
            if strcmpi(periodicidad, 'aperiodica')
                xlim(app.UIAxes3,[ro, rf]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonGenerar
        function ButtonGenerarPushed(app, event)
            f=app.FrecuenciaSlider.Value;
            ampli=app.AmplitudSlider.Value;
            Tmuestreo=app.TazamuestreoEditField.Value;
            % % Crear las señales continuas o discretas
            % x = -5:0.01:5; % Rango de tiempo para señales continuas
            x=app.RoEditField.Value:0.01:app.RfEditField.Value;
            xn = -5:1/Tmuestreo:5; % Muestreo para señales discretas
            % xn=app.RoEditField.Value:1/Tmuestreo:app.RfEditField.Value;
            % ruido_aperiodico = rand(size(x)); % Ruido en el rango [-0.5, 0.5]
            % ruido_aperiodico_discreto = rand(size(xn)); % Ruido en el rango [-0.5, 0.5]


            if app.DropDownSenales.Value == "Senoidal compleja" || app.DropDownSenales.Value == "Senoidal" || app.DropDownSenales.Value == "Exponencial compleja"
                app.FrecuenciaSliderLabel.Visible = 'on';
                app.FrecuenciaSlider.Visible = "on";
            else
                app.FrecuenciaSliderLabel.Visible = 'off';
                app.FrecuenciaSlider.Visible = "off";
            end
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end     


            switch app.DropDownSenales.Value
                    case 'Escalón unitario'    
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            
                            % Definir la señal continua y
                            % texto = 'La señal escalon unitario es una señal de potencia';
                            % msgbox(texto, 'Mensaje', 'modal');
                            y = ampli * (x >= 0);

                            % Definir la secuencia discreta xn y la señal discreta yn
                            yn = ampli* (xn >= 0);
                            
                            % Graficar la señal continua y
                            plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                            title(app.UIAxes,'Señal Continua y(t)');
                            xlabel(app.UIAxes,'Tiempo Continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            % Graficar la señal discreta yn
                            stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                            title(app.UIAxes2,'Señal Discreta y[n]');
                            xlabel(app.UIAxes2,'Índice Discreto (n)');
                            ylabel(app.UIAxes2,'Amplitud');
                            
                            % Ajustar los límites de los subplots para una mejor visualización
                            ylim(app.UIAxes2,[(app.RoEditField.Value), app.RfEditField.Value]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x >= 0);

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida
                            plot(app.UIAxes,t_repetido, y_repetida);
                            xlabel(app.UIAxes,'Tiempo continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal escalon unitario Periodica continua');

                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn >= 0);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Tiempo continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal escalon unitario Periodica discreta');

                        end
                    case 'Impulso unitario'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * (x == 0);
                            yn = ampli * (xn == 0);


                            % Graficar ambas señales
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Escalon Unitario');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 

                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x == 0);

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn == 0);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Tiempo continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Impulso Unitario');
                            
                        end


                    case 'Rampa'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * x .* (x >= 0);
                            yn = ampli * xn .* (xn >= 0);
                            % Graficar ambas señales
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Rampa');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * x .* (x >= 0);

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * xn .* (xn >= 0);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Impulso Unitario');                            

                        end

                    case 'Senoidal'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            y = ampli * sin(2*pi*f*x);
                            yn = ampli * sin(2*pi*f*xn);

                            % Graficar ambas señales
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Senoidal');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 
                            
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * sin(2*pi*f*x);

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * sin(2*pi*f*xn);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Senoidal');  
                        end


                    case 'Senoidal compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            yn = ampli * exp(1i*2*pi*f*xn);
                            real_part = real(y);
                            imaginary_part = imag(y);
                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,x, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                
                                stem(app.UIAxes2,xn, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes,x, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                                stem(app.UIAxes2,xn, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end

                        else 
                            
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            yn = ampli * exp(1i*2*pi*f*xn);
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,t_repetido, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                
                                stem(app.UIAxes2,tn_repetido, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes,t_repetido, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                                stem(app.UIAxes2,tn_repetido, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end
                        end


                    case 'Exponencial'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                alpha=1;
                                %%exponencial alpha positiva
                                y = ampli * exp(alpha*x);
                                yn = ampli * exp(alpha*xn);

                                % Graficar ambas señales
                                plot(app.UIAxes,x, y); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                                
                                stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                alpha=-1;
                                y = ampli * exp(alpha*x);
                                yn = ampli * exp(alpha*xn);
                                % Graficar ambas señales
                                plot(app.UIAxes,x, y); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                                
                                stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            end
                            
                        else
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                %%exponencial alpha positiva
                                y = ampli * exp(x);
                                yn = ampli * exp(xn);
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;
                                % Graficar ambas señales
                                plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                                
                                stem(app.UIAxes2,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                y = ampli * exp(-x);
                                yn = ampli * exp(-xn);
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;
                                % Graficar ambas señales
                                plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                                
                                stem(app.UIAxes2,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            end
                            

                        end


                    case 'Exponencial compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp((2*pi*f)*(x+(pi/4)));
                            yn = ampli * exp((2*pi*f)*(xn+(pi/4)));
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);

                            real_part = real(y);
                            imaginary_part = imag(y);
                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,x, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;
                                
                                stem(app.UIAxes2,xn, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);
                                % hold off;

                            else
                                %%plot complejo
                                plot(app.UIAxes,x, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;

                                stem(app.UIAxes2,xn, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);
                                % hold off;


                            end
                            
                        else
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);

                            app.ParteSwitch.Visible="on";
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,t_repetido, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                
                                stem(app.UIAxes2,tn_repetido, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes,t_repetido, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                                stem(app.UIAxes2,tn_repetido, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end
                        end

            end
        end

        % Button pushed function: DescomponercontinuaButton
        function DescomponercontinuaButtonPushed(app, event)
            f=app.FrecuenciaSlider.Value;
            ampli=app.AmplitudSlider.Value;
            % Crear las señales continuas o discretas
            x = -5:0.01:5; % Rango de tiempo para señales continuas
            x=app.PeriodoscontinuaEditField.Value:0.01:app.PeriodosdiscretaEditField.Value;
            xn = -5:5; % Muestreo para señales discretas
            xn=app.PeriodoscontinuaEditField.Value:app.PeriodosdiscretaEditField.Value;


            switch app.DropDownSenales.Value
                    case 'Escalón unitario'   
                        yn = ampli * (xn >= 0);
    
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        plot(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Crear una nueva figura para graficar la parte impar
                        plot(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Impulso unitario'
                        yn = ampli * (xn == 0);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        plot(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Crear una nueva figura para graficar la parte impar
                        plot(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Rampa'
                        yn = ampli * xn .* (xn >= 0);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        plot(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Crear una nueva figura para graficar la parte impar
                        plot(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Senoidal'
                        yn = ampli * sin(2*pi*f*xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        plot(app.UIAxes,xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes,'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes,'Tiempo Discreto (n)');
                        ylabel(app.UIAxes,'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar
                        plot(app.UIAxes2,xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2,'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2,'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2,'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Senoidal compleja'
                        yn = ampli * exp(1i*2*pi*f*xn);
                        
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + conj(flip(yn)));  % Parte par
                        yn_impar = 0.5 * (yn - conj(flip(yn)));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        plot(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        plot(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);  % Establecer límites en el eje y para el subplot de la señal discreta
                    case 'Exponencial'
                        yn = ampli * exp(-xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        plot(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        plot(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Exponencial compleja'
                        yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + conj(flip(yn)));  % Parte par
                        yn_impar = 0.5 * (yn - conj(flip(yn)));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        plot(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        plot(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);

            end
        end

        % Button pushed function: DescomponerdiscretaButton
        function DescomponerdiscretaButtonPushed(app, event)
            f=app.FrecuenciaSlider.Value;
            ampli=app.AmplitudSlider.Value;
            % Crear las señales continuas o discretas
            x = -5:0.01:5; % Rango de tiempo para señales continuas
            x=app.PeriodoscontinuaEditField.Value:0.01:app.PeriodosdiscretaEditField.Value;
            xn = -5:5; % Muestreo para señales discretas
            xn=app.PeriodoscontinuaEditField.Value:app.PeriodosdiscretaEditField.Value;


            switch app.DropDownSenales.Value
                    case 'Escalón unitario'   
                        yn = ampli * (xn >= 0);
    
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        stem(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);

                        ysuma=yn_par+yn_impar;
                        
                        % Crear una nueva figura para graficar la parte impar
                        stem(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Impulso unitario'
                        yn = ampli * (xn == 0);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        stem(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Crear una nueva figura para graficar la parte impar
                        stem(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Rampa'
                        yn = ampli * xn .* (xn >= 0);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        stem(app.UIAxes, xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Índice Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Crear una nueva figura para graficar la parte impar
                        stem(app.UIAxes2, xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Índice Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Senoidal'
                        yn = ampli * sin(2*pi*f*xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par
                        stem(app.UIAxes,xn, yn_par, 'r', 'LineWidth', 2);
                        title(app.UIAxes,'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes,'Tiempo Discreto (n)');
                        ylabel(app.UIAxes,'Amplitud');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar
                        stem(app.UIAxes2,xn, yn_impar, 'b', 'LineWidth', 2);
                        title(app.UIAxes2,'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2,'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2,'Amplitud');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Senoidal compleja'
                        app.ParteSwitch.Visible="on";

                        yn = ampli * exp(1i*2*pi*f*xn);

                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + conj(flip(yn)));  % Parte par
                        yn_impar = 0.5 * (yn - conj(flip(yn)));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        stem(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        stem(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);
                        

                    case 'Exponencial'
                        yn = ampli * exp(-xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + flip(yn));  % Parte par
                        yn_impar = 0.5 * (yn - flip(yn));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        stem(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        stem(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);
                    case 'Exponencial compleja'
                        yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                        % Dividir la señal en partes par e impar
                        yn_par = 0.5 * (yn + conj(flip(yn)));  % Parte par
                        yn_impar = 0.5 * (yn - conj(flip(yn)));  % Parte impar
                        
                        % Graficar la parte par en app.UIAxes1
                        stem(app.UIAxes, xn, real(yn_par), 'r', 'LineWidth', 2);
                        title(app.UIAxes, 'Parte Par de la Señal Discreta');
                        xlabel(app.UIAxes, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes,[(-100), 100]);
                        
                        % Graficar la parte impar en app.UIAxes2
                        stem(app.UIAxes2, xn, real(yn_impar), 'b', 'LineWidth', 2);
                        title(app.UIAxes2, 'Parte Impar de la Señal Discreta');
                        xlabel(app.UIAxes2, 'Tiempo Discreto (n)');
                        ylabel(app.UIAxes2, 'Amplitud (Parte Real)');
                        ylim(app.UIAxes2,[(-100), 100]);

            end


        end

        % Value changed function: ParteSwitch
        function ParteSwitchValueChanged(app, event)
            if app.DropDownSenales.Value == "Senoidal compleja"
                app.ParteSwitch.Visible="on"
            else
                app.ParteSwitch.Visible="off"
            end
            if app.DropDownSenales.Value == "Exponencial compleja"
                app.ParteSwitch.Visible="on"
            else
                app.ParteSwitch.Visible="off"
            end
        end

        % Value changed function: alphaSwitch
        function alphaSwitchValueChanged(app, event)
            if app.DropDownSenales.Value == "Exponencial"
                app.alphaSwitch.Visible="on"
            else
                app.alphaSwitch.Visible="off"
            end            
        end

        % Value changed function: PeriodoscontinuaEditField
        function PeriodoscontinuaEditFieldValueChanged(app, event)
       
        end

        % Value changed function: FrecuenciaSlider
        function FrecuenciaSliderValueChanged(app, event)
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            factor = app.NumEditField.Value;
            factorCorrimiento = app.FactorcorrimientoEditField.Value;
            factor2= app.FactordeescalaEditField.Value; 

            if selectedButton==app.DependienteButton
                app.Seal1Button.Visible="on";
                app.Seal2Button.Visible="on";
            else
                app.Seal1Button.Visible="off";
                app.Seal2Button.Visible ="off";
            end
            

            % Inicializacion de x dependiendo si esta seleccionado continua
            % o discreta
            if app.ButtonGroup_4.SelectedObject == app.ContinuaButton
                x = abs(factor)*app.RoEditField.Value:0.01:app.RfEditField.Value*abs(factor);
            else 
                x = -5/abs(factor):1/app.TazamuestreoEditField.Value:5/abs(factor);
            end

            y = [];
            f = app.FrecuenciaSlider.Value;
            a = app.AmplitudSlider.Value;
            ro = app.RoEditField.Value;
            rf = app.RfEditField.Value;
            periodicidad = 'periodica';
            titulo = '';
            
            %______________________________________________________________
            %
            %---------SI ES SELECCIONADA LA VARIABLE INDEPENDIENTE---------
            %______________________________________________________________

            if selectedButton == app.IndependienteButton

                %----------------------------------------------------------
                %       Configuración de visibilidad en los elementos
                %----------------------------------------------------------

                app.TipodeoperacionLabel.Visible = 'on';
                app.ButtonGroup_3.Visible = 'on';
                app.OperarenlasealLabel.Visible = 'on';
                app.ButtonGroup_4.Visible = 'on';
                app.ButtonGroup_5.Visible = 'off';
                app.NumEditField.Visible = 'on';
                app.y1txLabel.Visible = 'on';
                app.tLabel.Visible = 'on';
                app.FactorcorrimientoEditField.Visible = 'on';
                app.Label.Visible = 'on';
                app.FactordeescalaEditField.Visible ='off';
                app.FactordeescalaEditFieldLabel.Visible='off';
                app.UIAxes3.Visible = 'on';

                %----------------------------------------------------------
                %           Configuración por defecto del plot
                %----------------------------------------------------------

                ylim(app.UIAxes3,[-10, 10]);
                ylabel(app.UIAxes3,'Amplitud');

                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------

                % Señal escalon unitario
                if strcmpi(app.DropDownSenales.Value, 'Escalón unitario')
                    y = 5 * (x >= 0);
                    titulo = 'Escalon unitario';

                % Señal impulso unitario
                elseif strcmpi(app.DropDownSenales.Value, 'Impulso unitario')
                    y = 5 * (x == 0);
                    titulo = 'Impulso unitario';

                % Señal rampa
                elseif strcmpi(app.DropDownSenales.Value, 'Rampa')
                    y = 5 * x .* (x >= 0);
                    titulo = 'Rampa';

                % Señal escalon senoidal
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal')
                    y = 5 * sin(2*pi*x*f);
                    titulo = 'Senoidal';

                % Señal senoidal compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal compleja')
                    y = 5 * exp(1i*2*pi*x*f);
                    titulo = 'Senoidal compleja';

                % Señal exponencial
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial')
                    y = 5 * exp(x);
                    titulo = 'Exponencial';

                % Señal exponencial compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial compleja')
                    y = 5 * exp(1i*2*pi*x) .* exp(-x);
                    titulo = 'Exponencial compleja';
                end

                %----------------------------------------------------------
                %               Identificacion de periodicidad
                %----------------------------------------------------------

                if app.ButtonGroup.SelectedObject == app.PeriodicaButton
                    y=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                    x=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y));
                end

                %----------------------------------------------------------
                %               Seleccion del tipo de operacion
                %----------------------------------------------------------

                % Escalamiento de tiempo
                if app.ButtonGroup_3.SelectedObject == app.EscalamientodetiempoButton
                    x = (x - factorCorrimiento)/factor;
                    
                % Reflexion
                elseif app.ButtonGroup_3.SelectedObject == app.ReflexionButton
                    x =-x;
                    
                    % Desaparecemos los editField
                    app.NumEditField.Visible = 'off';
                    app.tLabel.Visible = 'off';
                    app.FactorcorrimientoEditField.Visible = 'off';
                    app.Label.Visible = 'off';

                % Corrimiento de tiempo
                elseif app.ButtonGroup_3.SelectedObject == app.CorrimientodetiempoButton
                    x = (x - factorCorrimiento)/factor;
                end

                %----------------------------------------------------------
                %          Seleccion de la señal discreta o continua
                %----------------------------------------------------------

                if app.ButtonGroup_4.SelectedObject == app.ContinuaButton
                    plotear(app,app.UIAxes3, x, y, ro, rf, titulo, 'continua', periodicidad);

                elseif app.ButtonGroup_4.SelectedObject == app.DiscretaButton
                    plotear(app,app.UIAxes3, x, y, -5, 5, titulo, 'discreta', periodicidad);

                end

                %-----------------------------------------
                %-----------------------------------------

            
            %______________________________________________________________
            %
            %-----------SI ES SELECCIONADA LA VARIABLE DEPENDIENTE---------
            %______________________________________________________________

            elseif selectedButton == app.DependienteButton
                
                %----------------------------------------------------------
                %       Configuración de visibilidad en los elementos
                %----------------------------------------------------------

                app.TipodeoperacionLabel.Visible = 'on';
                app.ButtonGroup_3.Visible = 'off';
                app.ButtonGroup_4.Visible = 'on';
                app.ButtonGroup_5.Visible = 'on';
                app.UIAxes3.Visible = 'on';
                app.NumEditField.Visible = 'off'
                app.y1txLabel.Visible = 'off';
                app.tLabel.Visible = 'off';
                app.FactordeescalaEditField.Visible ='on';
                app.FactordeescalaEditFieldLabel.Visible='on';
                plot(app.UIAxes3, 0);


                ylim(app.UIAxes3,[-10, 10]);
                ylabel(app.UIAxes3,'Amplitud');
          

            %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------

                % Señal escalon unitario
                if strcmpi(app.DropDownSenales.Value, 'Escalón unitario')
                    y = 5 * (x >= 0);
                    titulo = 'Escalon unitario';

                % Señal impulso unitario
                elseif strcmpi(app.DropDownSenales.Value, 'Impulso unitario')
                    y = 5 * (x == 0);
                    titulo = 'Impulso unitario';

                % Señal rampa
                elseif strcmpi(app.DropDownSenales.Value, 'Rampa')
                    y = 5 * x .* (x >= 0);
                    titulo = 'Rampa';

                % Señal escalon senoidal
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal')
                    y = 5 * sin(2*pi*x*f);
                    titulo = 'Senoidal';

                % Señal senoidal compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal compleja')
                    y = 5 * exp(1i*2*pi*x*f);
                    titulo = 'Senoidal compleja';

                % Señal exponencial
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial')
                    y = 5 * exp(x);
                    titulo = 'Exponencial';

                % Señal exponencial compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial compleja')
                    y = 5 * exp(1i*2*pi*x) .* exp(-x);
                    titulo = 'Exponencial compleja';
                end


                %----------------------------------------------------------
                %               Seleccion del tipo de operacion
                %----------------------------------------------------------
                    
                         if app.ButtonGroup_5.SelectedObject == app.EscalamientodeamplitudButton            
                            
                            % Realizar el escalamiento de amplitud
                            y= factor2 * y;
       

                        elseif app.ButtonGroup_5.SelectedObject == app.DiferenciacinButton
                            y = diff(y) ./ diff(x);
                            % Desaparecemos los editField
                            app.FactordeescalaEditField.Visible = 'off';
                            app.FactordeescalaEditFieldLabel.Visible = 'off';
                     
                        elseif app.ButtonGroup_5.SelectedObject == app.IntegracinButton
                            y = cumtrapz(x, y);
                           
                            % Desaparecemos los editField
                            app.FactordeescalaEditField.Visible = 'off';
                            app.FactordeescalaEditFieldLabel.Visible = 'off';

                        elseif app.ButtonGroup_5.SelectedObject == app.SumaButton
                            y = app.y1continua
                            % plot(app.UIAxes3,x, y_mod, 'b', 'LineWidth', 2), title(app.UIAxes3,'Integracion de la señal');
                            % xlabel(app.UIAxes3,'Tiempo Continuo');
                            % ylabel(app.UIAxes3,'Integral de y respecto a t');
                            if app.ButtonGroup_4.SelectedObject == app.ContinuaButton
                                %%sumas continuas}
                                y= app.y1continua+ app.y2continua;     

                            else
                                %%sumas discretas
                                y=app.y1discreta +app.y2discreta;
                            end
                         elseif app.ButtonGroup_5.SelectedObject==app.MultiplicacinButton
                             %%multi
                             if app.ButtonGroup_4.SelectedObject==app.ContinuaButton
                                 %%multi de continuas
                                 y= app.y1continua .* app.y2continua;

                             else
                                 %%discretas
                                 y=app.y1discreta .*app.y2discreta;

                             end
                         end

                           %----------------------------------------------------------
                %          Seleccion de la señal discreta o continua
                %----------------------------------------------------------

                if app.ButtonGroup_5.SelectedObject == app.DiferenciacinButton
                    x=x(1:end-1);

                   
                    if app.ButtonGroup_4.SelectedObject == app.ContinuaButton
                    plotear(app,app.UIAxes3, x, y, ro, rf, titulo, 'continua', periodicidad);
                     

                    else 
                        app.ButtonGroup_4.SelectedObject == app.DiscretaButton
                           plotear(app,app.UIAxes3, x, y, -5, 5, titulo, 'discreta', periodicidad);

                    end
                
                else 
                    
                    if app.ButtonGroup_4.SelectedObject == app.ContinuaButton
                          plotear(app,app.UIAxes3, x, y, ro, rf, titulo, 'continua', periodicidad);
                     

                    else 
                        app.ButtonGroup_4.SelectedObject == app.DiscretaButton
                         plotear(app,app.UIAxes3, x, y, -5, 5, titulo, 'discreta', periodicidad);
        
                    end
                end
            end 
            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            ButtonGroup_2SelectionChanged(app, event);
            
            % Obtiene la posición actual del Label
            currentPosition = app.y1txLabel.Position;

            %----------------------------------------------------------
            %     Modificacion del texto dependiendo la selección
            %----------------------------------------------------------

            % Si es escalamiento de tiempo se coloca un asterisco
            if selectedButton == app.EscalamientodetiempoButton
                app.y1txLabel.Text = 'y1(t)  =  x( '
                if currentPosition(1) > 100 
                    app.y1txLabel.Position = [currentPosition(1) - 50, currentPosition(2), currentPosition(3), currentPosition(4)];
                end
            
            % Si es corrimiento de tiempo se coloca un mas
            elseif selectedButton == app.CorrimientodetiempoButton
                app.y1txLabel.Text = 'y1(t)  =  x( '
                if currentPosition(1) > 100
                    app.y1txLabel.Position = [currentPosition(1) - 50, currentPosition(2), currentPosition(3), currentPosition(4)];
                end

            % Si es reflexion se aumenta left del label para centrarlo
            elseif selectedButton == app.ReflexionButton
                app.y1txLabel.Text = 'y1(t) = -x( t )'
                app.y1txLabel.Position = [currentPosition(1) + 50, currentPosition(2), currentPosition(3), currentPosition(4)];
            end
        end

        % Value changed function: DropDownSenales
        function DropDownSenalesValueChanged(app, event)
            value = app.DropDownSenales.Value;
                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end  
                
                % Señal escalon unitario
                if strcmpi(app.DropDownSenales.Value, 'Escalón unitario')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";           

                % Señal impulso unitario
                elseif strcmpi(app.DropDownSenales.Value, 'Impulso unitario')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";

                % Señal rampa
                elseif strcmpi(app.DropDownSenales.Value, 'Rampa')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";

                % Señal senoidal
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";

                % Señal senoidal compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal compleja')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";

                % Señal exponencial
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial')
                    app.alphaSwitch.Visible="on"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";


                % Señal exponencial compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial compleja')
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";
                    
                end

            if strcmpi(app.DropDownSenales.Value, 'Senoidal')
                app.RoEditField.Value = -1;
                app.RfEditField.Value = 1;
            end
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Value changed function: RoEditField
        function RoEditFieldValueChanged(app, event)
            value = app.RoEditField.Value;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Value changed function: RfEditField
        function RfEditFieldValueChanged(app, event)
            value = app.RfEditField.Value;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Button pushed function: PoEButton
        function PoEButtonPushed(app, event)
x = app.RoEditField.Value:0.01:app.RfEditField.Value;
ampli = app.AmplitudSlider.Value;
f = app.FrecuenciaSlider.Value;

function tipo_de_senal(y, x)
    % Calcula la energía de la señal
    energia = sum(abs(y).^2) * (x(2) - x(1));

    % Calcula la potencia de la señal
    potencia = mean(abs(y).^2);

    % Compara energía y potencia para determinar el tipo de señal
    if isfinite(energia) && isfinite(potencia)
        if energia < potencia
            texto = ['La señal es una señal de potencia = ', num2str(power)];
            msgbox(texto, 'Mensaje', 'modal');
        else
            texto = ['La señal es una señal de energia = ', num2str(energia)];
            msgbox(texto, 'Mensaje', 'modal');
        end
    elseif isfinite(energia)
            texto = ['La señal es una señal de energia = ', num2str(energia)];
            msgbox(texto, 'Mensaje', 'modal');
    elseif isfinite(potencia)
            texto = ['La señal es una señal de potencia = ', num2str(power)];
            msgbox(texto, 'Mensaje', 'modal');
    else
            texto = ['La señal no es ni de potencia o energia '];
            msgbox(texto, 'Mensaje', 'modal');    
    end
end


        if app.ButtonGroup.SelectedObject == app.AperiodicaButton || app.ButtonGroup.SelectedObject == app.PeriodicaButton
         switch app.DropDownSenales.Value
            case 'Escalón unitario'
                %17
                y = ampli * (x >= 0);
                tipo_de_senal(y,x);
            case 'Impulso unitario'
                y = ampli * (x == 0);
                tipo_de_senal(y, x);
            case 'Rampa'
                y = ampli * x .* (x >= 0);
                tipo_de_senal(y, x);
            case 'Senoidal'
                y = ampli * sin(2 * pi * x);
                tipo_de_senal(y, x);
            case 'Senoidal compleja'
                y = ampli * exp(1i * 2 * pi * f * x);
                tipo_de_senal(y, x);
            case 'Exponencial'
                y = ampli * exp(x);
                tipo_de_senal(y, x);
            case 'Exponencial compleja'
                y = ampli * exp(1i * 2 * pi * f * x) .* exp(x);
                tipo_de_senal(y, x);
         end

        end


        end

        % Value changed function: NumEditField
        function NumEditFieldValueChanged(app, event)
            value = app.NumEditField.Value;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Value changed function: TazamuestreoEditField
        function TazamuestreoEditFieldValueChanged(app, event)
            value = app.TazamuestreoEditField.Value;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_5.SelectedObject;
            
            ButtonGroup_2SelectionChanged(app, event);


        end

        % Button pushed function: Seal1Button
        function Seal1ButtonPushed(app, event)
            f=app.FrecuenciaSlider.Value;
            ampli=app.AmplitudSlider.Value;
            Tmuestreo=app.TazamuestreoEditField.Value;
            x=app.RoEditField.Value:0.01:app.RfEditField.Value;
            xn = -5:1/Tmuestreo:5; % Muestreo para señales discretas

            if app.DropDownSenales.Value == "Senoidal compleja" || app.DropDownSenales.Value == "Senoidal" || app.DropDownSenales.Value == "Exponencial compleja"
                app.FrecuenciaSliderLabel.Visible = 'on';
                app.FrecuenciaSlider.Visible = "on";
            else
                app.FrecuenciaSliderLabel.Visible = 'off';
                app.FrecuenciaSlider.Visible = "off";
            end
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end

            if app.ButtonGroup_4.SelectedObject==app.ContinuaButton

            switch app.DropDownSenales.Value
                    case 'Escalón unitario'    
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * (x >= 0);
                            app.y1continua=y;
                            
                            % Graficar la señal continua y
                            plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                            title(app.UIAxes,'Señal Continua y(t)');
                            xlabel(app.UIAxes,'Tiempo Continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            % Ajustar los límites de los subplots para una mejor visualización
                            ylim(app.UIAxes,[(app.RoEditField.Value), app.RfEditField.Value]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x >= 0);
                            app.y1continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida
                            plot(app.UIAxes,t_repetido, y_repetida);
                            xlabel(app.UIAxes,'Tiempo continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal escalon unitario Periodica continua');

                        end
                    case 'Impulso unitario'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * (x == 0);
                            app.y1continua=y;

                            % Graficar ambas señales
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 

                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x == 0);
                            app.y1continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y                            
                        end


                    case 'Rampa'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * x .* (x >= 0);
                            app.y1continua=y;
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta

                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * x .* (x >= 0);
                            app.y1continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                        end

                    case 'Senoidal'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            y = ampli * sin(2*pi*f*x);
                            app.y1continua=y;
                            % Graficar ambas señales
                            plot(app.UIAxes,x, y); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                           
                        else 
                            
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * sin(2*pi*f*x);
                            app.y1continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                        end


                    case 'Senoidal compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            app.y1continua=y;
                            real_part = real(y);
                            imaginary_part = imag(y);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,x, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);


                            else
                                %%plot complejo
                                plot(app.UIAxes,x, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            end

                        else 
                            
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            app.y1continua=y;
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,t_repetido, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                
                            else
                                %%plot complejo
                                plot(app.UIAxes,t_repetido, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            end
                        end


                    case 'Exponencial'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                alpha=1;
                                %%exponencial alpha positiva
                                y = ampli * exp(alpha*x);
                                app.y1continua=y;

                                % Graficar ambas señales
                                plot(app.UIAxes,x, y); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y

                            else
                                %%exponencial alpha negativa
                                alpha=-1;
                                y = ampli * exp(alpha*x);
                                app.y1continua=y;
                                % Graficar ambas señales
                                plot(app.UIAxes,x, y); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y

                            end
                            
                        else
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                %%exponencial alpha positiva
                                y = ampli * exp(x);
                                app.y1continua=y;
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                % Graficar ambas señales
                                plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y

                            else
                                %%exponencial alpha negativa
                                y = ampli * exp(-x);
                                app.y1continua=y;
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                % Graficar ambas señales
                                plot(app.UIAxes,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y

                            end
                            

                        end


                    case 'Exponencial compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            app.y1continua=y;

                            real_part = real(y);
                            imaginary_part = imag(y);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,x, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;

                            else
                                %%plot complejo
                                plot(app.UIAxes,x, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;

                            end
                            
                        else
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            app.y1continua=y;

                            app.ParteSwitch.Visible="on";
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes,t_repetido, real_part);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes,t_repetido, imaginary_part);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            end
                        end

            end


            else 
            %%Para discretas
            switch app.DropDownSenales.Value
                    case 'Escalón unitario'    
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            
                            % Definir la secuencia discreta xn y la señal discreta yn
                            yn = ampli* (xn >= 0);
                            app.y1discreta=yn;
                            
                            % Graficar la señal discreta yn
                            stem(app.UIAxes,xn, yn, 'r', 'LineWidth', 2);
                            title(app.UIAxes,'Señal Discreta y[n]');
                            xlabel(app.UIAxes,'Índice Discreto (n)');
                            ylabel(app.UIAxes,'Amplitud');
                            
                            % Ajustar los límites de los subplots para una mejor visualización
                            ylim(app.UIAxes,[(app.RoEditField.Value), app.RfEditField.Value]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else

                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn >= 0);
                            app.y1discreta=yn;

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes,'Tiempo continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal escalon unitario Periodica discreta');

                        end
                    case 'Impulso unitario'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            yn = ampli * (xn == 0);
                            app.y1discreta=yn;
                            stem(app.UIAxes,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes,'Señal Discreta - Escalon Unitario');
                            xlabel(app.UIAxes,'Muestra');
                            ylabel(app.UIAxes,'Amplitud');
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 

                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn == 0);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes,'Tiempo continuo');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal Discreta - Impulso Unitario');
                            
                        end


                    case 'Rampa'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            yn = ampli * xn .* (xn >= 0);
                            app.y1discreta=yn;
                           
                            stem(app.UIAxes,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes,'Señal Discreta - Rampa');
                            xlabel(app.UIAxes,'Muestra');
                            ylabel(app.UIAxes,'Amplitud');
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * xn .* (xn >= 0);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes,'Muestra');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal Discreta - Impulso Unitario');                            

                        end

                    case 'Senoidal'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            yn = ampli * sin(2*pi*f*xn);
                            app.y1discreta=yn;
                            
                            stem(app.UIAxes,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes,'Señal Discreta - Senoidal');
                            xlabel(app.UIAxes,'Muestra');
                            ylabel(app.UIAxes,'Amplitud');
                            ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * sin(2*pi*f*xn);

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes,'Muestra');
                            ylabel(app.UIAxes,'Amplitud');
                            title(app.UIAxes,'Señal Discreta - Senoidal');  
                        end


                    case 'Senoidal compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn);
                            app.y1discreta=yn;
                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                stem(app.UIAxes,xn, real_partn);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes,xn, imaginary_partn);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);


                            end

                        else 
                            
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn);
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                stem(app.UIAxes,tn_repetido, real_partn);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes,tn_repetido, imaginary_partn);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);


                            end
                        end


                    case 'Exponencial'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                alpha=1;
                                %%exponencial alpha positiva
                                yn = ampli * exp(alpha*xn);
                                app.y1discreta=yn;
                                stem(app.UIAxes,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes,'Muestra');
                                ylabel(app.UIAxes,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                alpha=-1;
                                yn = ampli * exp(alpha*xn);
                                app.y1discreta=yn;

                                stem(app.UIAxes,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes,'Muestra');
                                ylabel(app.UIAxes,'Amplitud');
                            end
                            
                        else
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                %%exponencial alpha positiva
                                yn = ampli * exp(xn);
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;
                                
                                stem(app.UIAxes,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes,'Muestra');
                                ylabel(app.UIAxes,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                yn = ampli * exp(-xn);
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;

                                stem(app.UIAxes,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes,'Muestra');
                                ylabel(app.UIAxes,'Amplitud');
                            end
                            

                        end


                    case 'Exponencial compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                            app.y1discreta=yn;

                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                stem(app.UIAxes,xn, real_partn);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;

                            else
                                %%plot complejo
                                stem(app.UIAxes,xn, imaginary_partn);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);
                                % hold off;


                            end
                            
                        else
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                            app.y1discreta=yn;

                            app.ParteSwitch.Visible="on";
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real

                                stem(app.UIAxes,tn_repetido, real_partn);
                                title(app.UIAxes,'Parte Real');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes,tn_repetido, imaginary_partn);
                                title(app.UIAxes,'Parte imaginaria');
                                xlabel(app.UIAxes,'Tiempo (s)');
                                ylabel(app.UIAxes,'Amplitud');
                                ylim(app.UIAxes,[-ampli, ampli]);


                            end
                        end

            end
            end


        end

        % Button pushed function: Seal2Button
        function Seal2ButtonPushed(app, event)
                        f=app.FrecuenciaSlider.Value;
            ampli=app.AmplitudSlider.Value;
            Tmuestreo=app.TazamuestreoEditField.Value;
            x=app.RoEditField.Value:0.01:app.RfEditField.Value;
            xn = -5:1/Tmuestreo:5; % Muestreo para señales discretas

            if app.DropDownSenales.Value == "Senoidal compleja" || app.DropDownSenales.Value == "Senoidal" || app.DropDownSenales.Value == "Exponencial compleja"
                app.FrecuenciaSliderLabel.Visible = 'on';
                app.FrecuenciaSlider.Visible = "on";
            else
                app.FrecuenciaSliderLabel.Visible = 'off';
                app.FrecuenciaSlider.Visible = "off";
            end
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end

            if app.ButtonGroup_4.SelectedObject==app.ContinuaButton

            switch app.DropDownSenales.Value
                    case 'Escalón unitario'    
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * (x >= 0);
                            app.y2continua=y;
                            
                            % Graficar la señal continua y
                            plot(app.UIAxes2,x, y, 'b', 'LineWidth', 2);
                            title(app.UIAxes2,'Señal Continua y(t)');
                            xlabel(app.UIAxes2,'Tiempo Continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                            
                            % Ajustar los límites de los subplots para una mejor visualización
                            ylim(app.UIAxes2,[(app.RoEditField.Value), app.RfEditField.Value]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x >= 0);
                            app.y2continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida
                            plot(app.UIAxes2,t_repetido, y_repetida);
                            xlabel(app.UIAxes2,'Tiempo continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal escalon unitario Periodica continua');

                        end
                    case 'Impulso unitario'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * (x == 0);
                            app.y2continua=y;

                            % Graficar ambas señales
                            plot(app.UIAxes2,x, y); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 

                            %SEÑAL CONTINUA SOLO 
                            y = ampli * (x == 0);
                            app.y2continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes2,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Impulso Unitario'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y                            
                        end

                    case 'Rampa'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            y = ampli * x .* (x >= 0);
                            app.y2continua=y;
                            plot(app.UIAxes2,x, y); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta

                        else
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * x .* (x >= 0);
                            app.y2continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes2,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Rampa'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y
                        end

                    case 'Senoidal'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            y = ampli * sin(2*pi*f*x);
                            app.y2continua=y;

                            % Graficar ambas señales
                            plot(app.UIAxes2,x, y); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                           
                        else 
                            
                            %SEÑAL CONTINUA SOLO 
                            y = ampli * sin(2*pi*f*x);
                            app.y2continua=y;

                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            % Graficar la señal repetida                            
                            plot(app.UIAxes2,t_repetido, y_repetida); % Grafica la señal continua
                            title(app.UIAxes2,'Señal Continua - Senoidal'); % Título de la subtrama
                            xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                            ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y
                        end


                    case 'Senoidal compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            app.y2continua=y;
                            real_part = real(y);
                            imaginary_part = imag(y);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes2,x, real_part);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            else
                                %%plot complejo
                                plot(app.UIAxes2,x, imaginary_part);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            end

                        else 
                            
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x);
                            app.y2continua=y;
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes2,t_repetido, real_part);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);
                                
                            else
                                %%plot complejo
                                plot(app.UIAxes2,t_repetido, imaginary_part);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            end
                        end


                    case 'Exponencial'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.alphaSwitch.Visible="on";

                            if app.alphaSwitch.Value == "Positivo"
                                alpha=1;
                                %%exponencial alpha positiva
                                y = ampli * exp(alpha*x);
                                app.y2continua=y;

                                % Graficar ambas señales
                                plot(app.UIAxes2,x, y); % Grafica la señal continua
                                title(app.UIAxes2,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y

                            else
                                %%exponencial alpha negativa
                                alpha=-1;
                                y = ampli * exp(alpha*x);
                                app.y2continua=y;
                                % Graficar ambas señales
                                plot(app.UIAxes2,x, y); % Grafica la señal continua
                                title(app.UIAxes2,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y

                            end
                            
                        else
                            app.alphaSwitch.Visible="on";

                            if app.alphaSwitch.Value == "Positivo"
                                %%exponencial alpha positiva
                                y = ampli * exp(x);
                                app.y2continua=y;
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                % Graficar ambas señales
                                plot(app.UIAxes2,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes2,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y

                            else
                                %%exponencial alpha negativa
                                y = ampli * exp(-x);
                                app.y2continua=y;
                                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                                % Graficar ambas señales
                                plot(app.UIAxes2,t_repetido, y_repetida); % Grafica la señal continua
                                title(app.UIAxes2,'Señal Continua - Exponencial'); % Título de la subtrama
                                xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                                ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje y

                            end
                        end

                    case 'Exponencial compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            app.y2continua=y;

                            real_part = real(y);
                            imaginary_part = imag(y);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes2,x, real_part);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes2,x, imaginary_part);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            end
                            
                        else
                            y = ampli * exp(1i*2*pi*f*x) .* exp(-x);
                            app.y2continua=y;

                            app.ParteSwitch.Visible="on";
                            y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);
                            t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));
                            real_part = real(y_repetida);
                            imaginary_part = imag(y_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                plot(app.UIAxes2,t_repetido, real_part);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                plot(app.UIAxes2,t_repetido, imaginary_part);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            end
                        end

            end


            else 
            %%Para discretas
            switch app.DropDownSenales.Value
                    case 'Escalón unitario'    
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            
                            % Definir la secuencia discreta xn y la señal discreta yn
                            yn = ampli* (xn >= 0);
                            app.y2discreta=yn;
                            
                            % Graficar la señal discreta yn
                            stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                            title(app.UIAxes2,'Señal Discreta y[n]');
                            xlabel(app.UIAxes2,'Índice Discreto (n)');
                            ylabel(app.UIAxes2,'Amplitud');
                            
                            % Ajustar los límites de los subplots para una mejor visualización
                            ylim(app.UIAxes2,[(app.RoEditField.Value), app.RfEditField.Value]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else

                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn >= 0);
                            app.y2discreta=yn;

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Tiempo continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal escalon unitario Periodica discreta');

                        end
                    case 'Impulso unitario'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            yn = ampli * (xn == 0);
                            app.y2discreta=yn;
                            
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Escalon Unitario');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 

                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * (xn == 0);
                            app.y2discreta=yn;

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Tiempo continuo');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Impulso Unitario');
                            
                        end


                    case 'Rampa'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            yn = ampli * xn .* (xn >= 0);
                            app.y2discreta=yn;
                           
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Rampa');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * xn .* (xn >= 0);
                            app.y2discreta=yn;

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Impulso Unitario');                            

                        end

                    case 'Senoidal'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            yn = ampli * sin(2*pi*f*xn);
                            app.y2discreta=yn;
                            
                            stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                            title(app.UIAxes2,'Señal Discreta - Senoidal');
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
                        else 
                            %SEÑAL DISCRETA SOLO 
                            yn = ampli * sin(2*pi*f*xn);
                            app.y2discreta=yn;

                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);

                            tn_repetido=0:length(yn_repetida)-1;
                            % Graficar la señal repetida
                            stem(app.UIAxes2,tn_repetido, yn_repetida);
                            xlabel(app.UIAxes2,'Muestra');
                            ylabel(app.UIAxes2,'Amplitud');
                            title(app.UIAxes2,'Señal Discreta - Senoidal');  
                        end


                    case 'Senoidal compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn);
                            app.y2discreta=yn;
                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                stem(app.UIAxes2,xn, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes2,xn, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end

                        else 
                            
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn);
                            app.y2discreta=yn;
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real
                                stem(app.UIAxes2,tn_repetido, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes2,tn_repetido, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end
                        end


                    case 'Exponencial'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                alpha=1;
                                %%exponencial alpha positiva
                                yn = ampli * exp(alpha*xn);
                                app.y2discreta=yn;
                                stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                alpha=-1;
                                yn = ampli * exp(alpha*xn);
                                app.y2discreta=yn;

                                stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            end
                            
                        else
                            app.alphaSwitch.Visible="on";


                            if app.alphaSwitch.Value == "Positivo"
                                %%exponencial alpha positiva
                                yn = ampli * exp(xn);
                                app.y2discreta=yn;
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;
                                
                                stem(app.UIAxes2,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            else
                                %%exponencial alpha negativa
                                yn = ampli * exp(-xn);
                                app.y2discreta=yn;
                                yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                                tn_repetido=0:length(yn_repetida)-1;

                                stem(app.UIAxes2,tn_repetido, yn_repetida); % Grafica la señal discreta utilizando un gráfico de tallos
                                title(app.UIAxes2,'Señal Discreta - Exponencial');
                                xlabel(app.UIAxes2,'Muestra');
                                ylabel(app.UIAxes2,'Amplitud');
                            end
                            

                        end


                    case 'Exponencial compleja'
                        if app.ButtonGroup.SelectedObject == app.AperiodicaButton
                            app.FrecuenciaSliderLabel.Visible = 'on';
                            app.FrecuenciaSlider.Visible="on";
                            app.ParteSwitch.Visible="on";
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                            app.y2discreta=yn;

                            real_partn = real(yn);
                            imaginary_partn = imag(yn);

                            if app.ParteSwitch.Value == "Real"
                                stem(app.UIAxes2,xn, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);
                                % hold off;

                            else
                                %%plot complejo
                                stem(app.UIAxes2,xn, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);
                                % hold off;


                            end
                            
                        else
                            yn = ampli * exp(1i*2*pi*f*xn) .* exp(-xn);
                            app.y2discreta=yn;

                            app.ParteSwitch.Visible="on";
                            yn_repetida=repmat(yn,1,app.PeriodosdiscretaEditField.Value);
                            tn_repetido=0:length(yn_repetida)-1;
                            real_partn = real(yn_repetida);
                            imaginary_partn = imag(yn_repetida);

                            if app.ParteSwitch.Value == "Real"
                                %%plot real

                                stem(app.UIAxes2,tn_repetido, real_partn);
                                title(app.UIAxes2,'Parte Real');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);

                            else
                                %%plot complejo
                                stem(app.UIAxes2,tn_repetido, imaginary_partn);
                                title(app.UIAxes2,'Parte imaginaria');
                                xlabel(app.UIAxes2,'Tiempo (s)');
                                ylabel(app.UIAxes2,'Amplitud');
                                ylim(app.UIAxes2,[-ampli, ampli]);


                            end
                        end

            end
            end

        end

        % Size changed function: ButtonGroup_4
        function ButtonGroup_4SizeChanged(app, event)
            position = app.ButtonGroup_4.Position;
            
        end

        % Value changed function: FactordeescalaEditField
        function FactordeescalaEditFieldValueChanged(app, event)
            value = app.FactordeescalaEditField.Value;
            
        end

        % Value changed function: FactorcorrimientoEditField
        function FactorcorrimientoEditFieldValueChanged(app, event)
            value = app.FactorcorrimientoEditField.Value;
            ButtonGroup_2SelectionChanged(app, event);
        end

        % Button pushed function: FuncionconvButton
        function FuncionconvButtonPushed(app, event)
                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------
                xparay=app.xdey;
                xparag=app.xdeg;
                f=app.FrecuenciaSlider.Value;
                ampli=app.AmplitudSlider.Value;

                y=app.senaly;
                g=app.senalg;

                titulo=app.tituloy;
                titulo2=app.titulog;


                titulo3 = ['Señal ', titulo, ' y señal ', titulo2];

                
                % Convolución de las señales
                tic;
                s = conv(y, g, "same");  % 'same' realiza la convolución manteniendo la longitud original
                tiempo_ejecucion=toc;
                app.Label_3.Text=num2str(tiempo_ejecucion);
                
                % Tiempo resultante de la convolución
                
                % Gráficas
                t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
                
                plot(app.UIAxes,xparay,y);
                hold(app.UIAxes,'on');
                plot (app.UIAxes,xparag,g);
                hold(app.UIAxes,'off');

                % leyenda
                title(app.UIAxes,titulo3);
                xlabel(app.UIAxes,'Tiempo');
                ylabel(app.UIAxes,'Amplitud');
                legend(app.UIAxes, titulo, titulo2);
                
                % Tiempo resultante de la convolución
                t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
                
                
                % Gráficas conv
                titulo3 = ['La convolución de ', titulo, ' y ', titulo2];
                
                plot(app.UIAxes2,t_resultante,s);

                % leyenda
                title(app.UIAxes2,titulo3);
                xlabel(app.UIAxes2,'Tiempo');
                ylabel(app.UIAxes2,'Amplitud');


        end

        % Button pushed function: AlgoritmoconvButton
        function AlgoritmoconvButtonPushed(app, event)
                x=app.RoEditField.Value:0.01:app.RfEditField.Value;
                f=app.FrecuenciaSlider.Value;
                ampli=app.AmplitudSlider.Value;
            y=app.senaly;
            g=app.senalg;

            titulo=app.tituloy;
            titulo2=app.titulog;  
                xparay=app.xdey;
                xparag=app.xdeg;
            % Convolución manual
            tic;
            len_y = length(y);
            len_g = length(g);
            len_s = len_y + len_g - 1;
            s = zeros(1, len_s);

            % Reflejar la señal g
            g_reflejada = flip(g);
            
            % for n = 1:len_s
            %     for k = max(1, n - len_g + 1):min(len_y, n)
            %         s(n) = s(n) + y(k) * g(n - k + 1);
            %     end
            % end
            for n = 1:len_s
                for k_candidate = n - len_g + 1:n
                    if k_candidate >= 1 && k_candidate <= len_y
                        k = k_candidate;
                        s(n) = s(n) + y(k) * g_reflejada(n - k + 1);
                    end
                end
            end

            tiempo_de_ejecucion = toc;

            
            % Normalización (si es necesario)
            s = s * 0.01;  % Ajusta según el paso de tiempo
            
            % Tiempo resultante de la convolución
            t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
            
            
            % Gráficas
            plot(app.UIAxes,xparay,y);
            hold(app.UIAxes,'on');
            plot (app.UIAxes,xparag,g);
            hold(app.UIAxes,'off');

            title(app.UIAxes, ['Señal ', titulo, ' y señal ', titulo2]);
            xlabel(app.UIAxes, 'Tiempo');
            ylabel(app.UIAxes, 'Amplitud');
            legend(app.UIAxes, titulo, titulo2);
            
            % Gráficas de la convolución
            plot(app.UIAxes2, t_resultante, s);
            title(app.UIAxes2, ['La convolución de ', titulo, ' y ', titulo2]);
            xlabel(app.UIAxes2, 'Tiempo');
            ylabel(app.UIAxes2, 'Amplitud');

            app.Label_2.Text = num2str(tiempo_de_ejecucion);

        end

        % Button pushed function: CompararButton
        function CompararButtonPushed(app, event)
                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------

                x=app.RoEditField.Value:0.01:app.RfEditField.Value;
                f=app.FrecuenciaSlider.Value;
                ampli=app.AmplitudSlider.Value;
                y=app.senaly;
                g=app.senalg;

                titulo=app.tituloy;
                titulo2=app.titulog;
                % Convolución manual
                tic;
                len_y = length(y);
                len_g = length(g);
                len_s = len_y + len_g - 1;
                s = zeros(1, len_s);
                
                for n = 1:len_s
                    for k = max(1, n - len_g + 1):min(len_y, n)
                        s(n) = s(n) + y(k) * g(n - k + 1);
                    end
                end
                tiempo_de_ejecucion = toc;
    
                
                % Normalización 
                %s = s * 0.01;  % Ajusta según el paso de tiempo
                
                % Tiempo resultante de la convolución
                t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
                
                            
                % Gráficas de la convolución
                plot(app.UIAxes, t_resultante, s);
                title(app.UIAxes, ['La convolución manual de ', titulo, ' y ', titulo2]);
                xlabel(app.UIAxes, 'Tiempo');
                ylabel(app.UIAxes, 'Amplitud');
    
                app.Label_2.Text = num2str(tiempo_de_ejecucion);
                tiempo_de_ejecucion=0;


                % Convolución de las señales
                tic;
                s = conv(y, g, 'full');  % 'same' realiza la convolución manteniendo la longitud original
                tiempo_ejecucion=toc;
                app.Label_3.Text=num2str(tiempo_ejecucion);
                
                % Tiempo resultante de la convolución
                %t_resultante = 0:0.01:(length(s)-1)*0.01;
                t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;                
                                
                % Gráficas conv
                titulo3 = ['La convolución con función de ', titulo, ' y ', titulo2];
                                
                plot(app.UIAxes2,t_resultante,s);
                
                % leyenda
                title(app.UIAxes2,titulo3);
                xlabel(app.UIAxes2,'Tiempo');
                ylabel(app.UIAxes2,'Amplitud');


        end

        % Selection changed function: ModoButtonGroup
        function ModoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModoButtonGroup.SelectedObject;
            if selectedButton == app.GeneradorButton
                app.ConvolucinLabel.Visible="off";
                app.CompararButton.Visible="off";
                app.SealLabel.Visible="off";
                app.FuncionconvButton.Visible="off";
                app.AlgoritmoconvButton.Visible="off";
                app.TejecucionFuncLabel.Visible="off";
                app.TejecucionalgortimoLabel.Visible="off";
                app.Label_3.Visible="off";
                app.Label_2.Visible="off";
                app.Generarseal1Button.Visible="off"
                app.Generarseal2Button.Visible="off"
                %%Generador
                app.DescomponercontinuaButton.Visible="on"
                app.DescomponerdiscretaButton.Visible="on"
                app.ButtonGenerar.Visible="on"
                app.PoEButton.Visible="on"
                %%Correelación
                app.FuncionButton.Visible="off";
                app.AlgoritmoButton.Visible="off";

            elseif selectedButton==app.ConvolucionButton
                app.ConvolucinLabel.Visible="on";
                app.CompararButton.Visible="on";
                app.SealLabel.Visible="on";
                app.FuncionconvButton.Visible="on";
                app.AlgoritmoconvButton.Visible="on";
                app.TejecucionFuncLabel.Visible="on";
                app.TejecucionalgortimoLabel.Visible="on";
                app.Label_3.Visible="on";
                app.Label_2.Visible="on";
                app.Generarseal1Button.Visible="on"
                app.Generarseal2Button.Visible="on"
                %%Generador
                app.DescomponercontinuaButton.Visible="off";
                app.DescomponerdiscretaButton.Visible="off";
                app.ButtonGenerar.Visible="off";
                app.PoEButton.Visible="off";
                %%Correlación
                app.FuncionButton.Visible="off";
                app.AlgoritmoButton.Visible="off";
            elseif selectedButton==app.CorrelacionButton
                %%Generador
                app.DescomponercontinuaButton.Visible="off";
                app.DescomponerdiscretaButton.Visible="off";
                app.ButtonGenerar.Visible="off";
                app.PoEButton.Visible="off";
                %Convolucion 
                app.ConvolucinLabel.Visible="off";
                app.CompararButton.Visible="off";
                app.SealLabel.Visible="off";
                app.FuncionconvButton.Visible="off";
                app.AlgoritmoconvButton.Visible="off";
                app.TejecucionFuncLabel.Visible="off";
                app.TejecucionalgortimoLabel.Visible="off";
                app.Label_3.Visible="off";
                app.Label_2.Visible="off";
                app.Generarseal1Button.Visible="off";
                app.Generarseal2Button.Visible="off";
                %Correlación
                app.Generarseal1Button.Visible="on";
                app.Generarseal2Button.Visible="on";
                app.CorrelacinLabel.Visible="on";
                app.FuncionButton.Visible="on";
                app.AlgoritmoButton.Visible="on";


            end
            
        end

        % Button pushed function: Generarseal1Button
        function Generarseal1ButtonPushed(app, event)
                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------

                x=app.RoEditField.Value:0.01:app.RfEditField.Value;
                corrimiento=app.CorrimientoEditField.Value;
                f=app.FrecuenciaSlider.Value;
                ampli=app.AmplitudSlider.Value;
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end  
                
                % Señal escalon unitario
                if strcmpi(app.DropDownSenales.Value, 'Escalón unitario')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    y = ampli * (x >= 0);
                    
                    titulo = 'Escalon unitario';

                % Señal impulso unitario
                elseif strcmpi(app.DropDownSenales.Value, 'Impulso unitario')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    y = ampli * (x == 0);
                    titulo = 'Impulso unitario';

                % Señal rampa
                elseif strcmpi(app.DropDownSenales.Value, 'Rampa')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    y = ampli * x .* (x >= 0);
                    titulo = 'Rampa';

                % Señal senoidal
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    y = ampli * sin(2*pi*x*f);
                    titulo = 'Senoidal';

                % Señal senoidal compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal compleja')
                    app.alphaSwitch.Visible="off"
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";
                    y = ampli * exp(1i*2*pi*x*f);
                    if app.ParteSwitch.Value=="Real"
                        y=real(y);
                        titulo = 'Senoidal compleja (Parte real)';
                    else
                        y=imag(y);
                        titulo = 'Senoidal compleja (Parte imaginaria)';
                    end
                    

                % Señal exponencial
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial')
                    app.alphaSwitch.Visible="on"
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    if app.alphaSwitch.Value=="Positivo"
                        y = ampli * exp(x);
                        titulo = 'Exponencial positiva';
                    else
                        y = ampli * exp(-x);
                        titulo = 'Exponencial negativa';
                    end
                    

                % Señal exponencial compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial compleja')
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";
                    y = ampli * exp(1i*2*pi*x) .* exp(-x);
                    if app.ParteSwitch.Value=="Real"
                        y=real(y);
                        titulo = 'Exponencial compleja (Parte real)';
                    else
                        y=imag(y);
                        titulo = 'Exponencial compleja (Parte imaginaria)';
                    end
                    
                end

            %SEÑAL CONTINUA SOLO 
            if app.ButtonGroup.SelectedObject==app.PeriodicaButton
                x= x - corrimiento;
                
                y_repetida=repmat(y,1,app.PeriodoscontinuaEditField.Value);

                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));

                % Graficar la señal repetida
                plot(app.UIAxes,t_repetido, y_repetida);
                xlabel(app.UIAxes,'Tiempo continuo');
                ylabel(app.UIAxes,'Amplitud');
                title(app.UIAxes,titulo);
            else
                % Graficar
                x= x - corrimiento;
                plot(app.UIAxes,x, y); % Grafica la señal continua
                title(app.UIAxes,titulo); % Título de la subtrama
                xlabel(app.UIAxes,'Tiempo'); % Etiqueta del eje x
                ylabel(app.UIAxes,'Amplitud'); % Etiqueta del eje y
                ylim(app.UIAxes,[(-10), 10]);  % Establecer límites en el eje y para el subplot de la señal discreta
            end
            app.senaly=y;
            app.tituloy=titulo;
            app.xdey=x;


        end

        % Button pushed function: Generarseal2Button
        function Generarseal2ButtonPushed(app, event)
                %----------------------------------------------------------
                %           Identificación de la señal elemental
                %----------------------------------------------------------

                x=app.RoEditField.Value:0.01:app.RfEditField.Value;
                corrimiento=app.CorrimientoEditField.Value;
                f=app.FrecuenciaSlider.Value;
                ampli=app.AmplitudSlider.Value;
            if app.ButtonGroup.SelectedObject == "Aperiodica"
                app.PeriodoscontinuaEditField.Visible="off";
                app.PeriodosdiscretaEditField.Visible="off";
                app.PeriodoscontinuaEditFieldLabel.Visible="off";
                app.PeriodosdiscretaEditFieldLabel.Visible="off";
            else
                app.PeriodoscontinuaEditField.Visible="on";
                app.PeriodosdiscretaEditField.Visible="on";
                app.PeriodoscontinuaEditFieldLabel.Visible="on";
                app.PeriodosdiscretaEditFieldLabel.Visible="on";
            end  
                
                % Señal escalon unitario
                if strcmpi(app.DropDownSenales.Value, 'Escalón unitario')
                    app.alphaSwitch.Visible="off";
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    g = ampli * (x >= 0);
                    
                    titulo = 'Escalon unitario';

                % Señal impulso unitario
                elseif strcmpi(app.DropDownSenales.Value, 'Impulso unitario')
                    app.alphaSwitch.Visible="off";
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    g = ampli * (x == 0);
                    titulo = 'Impulso unitario';

                % Señal rampa
                elseif strcmpi(app.DropDownSenales.Value, 'Rampa')
                    app.alphaSwitch.Visible="off";
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    g = ampli * x .* (x >= 0);
                    titulo = 'Rampa';

                % Señal senoidal
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal')
                    app.alphaSwitch.Visible="off";
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    g = ampli * sin(2*pi*x*f);
                    titulo = 'Senoidal';

                % Señal senoidal compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Senoidal compleja')
                    app.alphaSwitch.Visible="off";
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";
                    g = ampli * exp(1i*2*pi*x*f);
                    if app.ParteSwitch.Value=="Real"
                        g=real(g);
                        titulo = 'Senoidal compleja (Parte real)';
                    elseif app.ParteSwitch.Value=="Compleja"
                        g=imag(g);
                        titulo = 'Senoidal compleja (Parte imaginaria)';
                    end
                    

                % Señal exponencial
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial')
                    app.alphaSwitch.Visible="on";
                    app.ParteSwitch.Visible="off";
                    app.FrecuenciaSliderLabel.Visible = 'off';
                    app.FrecuenciaSlider.Visible = "off";
                    if app.alphaSwitch.Value=="Positivo"
                        g = ampli * exp(x);
                        titulo = 'Exponencial positiva';
                    elseif app.alphaSwitch.Value=="Negativo"
                        g = ampli * exp(-x);
                        titulo = 'Exponencial negativa';
                    end
                    

                % Señal exponencial compleja
                elseif strcmpi(app.DropDownSenales.Value, 'Exponencial compleja')
                    app.ParteSwitch.Visible="on";
                    app.FrecuenciaSliderLabel.Visible = 'on';
                    app.FrecuenciaSlider.Visible = "on";
                    g = ampli * exp(1i*2*pi*x) .* exp(-x);
                    if app.ParteSwitch.Value=="Real"
                        g=real(g);
                        titulo = 'Exponencial compleja (Parte real)';
                    elseif app.ParteSwitch.Value=="Compleja"
                        g=imag(g);
                        titulo = 'Exponencial compleja (Parte imaginaria)';
                    end
                    
                end

            %SEÑAL CONTINUA SOLO 
            if app.ButtonGroup.SelectedObject==app.PeriodicaButton
                x= x - corrimiento;
                
                y_repetida=repmat(g,1,app.PeriodoscontinuaEditField.Value);

                t_repetido=linspace(0,(app.PeriodoscontinuaEditField.Value-1)*max(x),length(y_repetida));

                % Graficar la señal repetida
                plot(app.UIAxes2,t_repetido, y_repetida);
                xlabel(app.UIAxes2,'Tiempo continuo');
                ylabel(app.UIAxes2,'Amplitud');
                title(app.UIAxes2,titulo);
            else
                % Graficar
                x= x - corrimiento;
                plot(app.UIAxes2,x, g); % Grafica la señal continua
                title(app.UIAxes2,titulo); % Título de la subtrama
                xlabel(app.UIAxes2,'Tiempo'); % Etiqueta del eje x
                ylabel(app.UIAxes2,'Amplitud'); % Etiqueta del eje g
                ylim(app.UIAxes2,[(-10), 10]);  % Establecer límites en el eje g para el subplot de la señal discreta
            end
            app.senalg=g;
            app.titulog=titulo;
            app.xdeg=x;

        end

        % Button pushed function: FuncionButton
        function FuncionButtonPushed(app, event)
            y=app.senaly;
            g=app.senalg;

            titulo=app.tituloy;
            titulo2=app.titulog;  
                xparay=app.xdey;
                xparag=app.xdeg;
            % Convolución manual
            tic;
            len_y = length(y);
            len_g = length(g);
            len_s = len_y + len_g - 1;
            s = zeros(1, len_s);
            s=xcorr(y,g);

            tiempo_de_ejecucion = toc;

            
            % Normalización (si es necesario)
            s = s /max(abs(s));  % Ajusta según el paso de tiempo
            
            % Tiempo resultante de la convolución
            t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
            %t_resultante = -(length(y)-1):(length(g)-1);
            
            
            % Gráficas
            plot(app.UIAxes,xparay,y);
            hold(app.UIAxes,'on');
            plot (app.UIAxes,xparag,g);
            hold(app.UIAxes,'off');

            title(app.UIAxes, ['Señal ', titulo, ' y señal ', titulo2]);
            xlabel(app.UIAxes, 'Tiempo');
            ylabel(app.UIAxes, 'Amplitud');
            legend(app.UIAxes, titulo, titulo2);
                        
            % Gráficas de la convolución
            plot(app.UIAxes2, t_resultante, s);
            title(app.UIAxes2, ['La correlación cruzada de ', titulo, ' y ', titulo2]);
            xlabel(app.UIAxes2, 'Tiempo');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2,[-2,2])

            app.Label_2.Text = num2str(tiempo_de_ejecucion);

        end

        % Button pushed function: AlgoritmoButton
        function AlgoritmoButtonPushed(app, event)
            y=app.senaly;
            g=app.senalg;

            titulo=app.tituloy;
            titulo2=app.titulog;  
                xparay=app.xdey;
                xparag=app.xdeg;
            % Convolución manual
            tic;
            len_y = length(y);
            len_g = length(g);
            len_s = (len_y + len_g);
            s = zeros(1, len_s);
            
            % for n = 1:len_s
            %     for k = max(1, n - len_g + 1):min(len_y, n)
            %         s(n) = s(n) + y(k) * g(n - k + 1);
            %     end
            % end

            % for n = 1:len_s
            %     for k_candidate = n - len_g + 1:n
            %         if k_candidate >= 1 && k_candidate <= len_y
            %             k = k_candidate;
            %             s(n) = s(n) + y(k) * g(n - k + 1);
            %         end
            %     end
            % end

            % Realizar la correlación
            for n = 1:length(s)
                for k = 1:length(g)
                    if n - k >= 1 && n - k <= length(y)
                        s(n) = s(n) + y(n -k) * g(k);
                    end
                end
            end


            tiempo_de_ejecucion = toc;
            s = s /max(abs(s));

            
            % Normalización (si es necesario)
            %s = s * 0.01;  % Ajusta según el paso de tiempo
            
            % Tiempo resultante de la convolución
            t_resultante = app.RoEditField.Value:0.01:app.RoEditField.Value + (length(s) - 1) * 0.01;
            
            
            % Gráficas
            plot(app.UIAxes,xparay,y);
            hold(app.UIAxes,'on');
            plot (app.UIAxes,xparag,g);
            hold(app.UIAxes,'off');

            title(app.UIAxes, ['Señal ', titulo, ' y señal ', titulo2]);
            xlabel(app.UIAxes, 'Tiempo');
            ylabel(app.UIAxes, 'Amplitud');
            xlim(app.UIAxes,[-1,3]);
            legend(app.UIAxes, titulo, titulo2);
            
            % Gráficas de la convolución
            plot(app.UIAxes2, t_resultante, s);
            title(app.UIAxes2, ['La correlación de ', titulo, ' y ', titulo2]);
            xlabel(app.UIAxes2, 'Tiempo');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2,[-2,2])

            app.Label_2.Text = num2str(tiempo_de_ejecucion);            
            
            app.scorr=s;


        end

        % Drop down opening function: DropDownSenales
        function DropDownSenalesOpening(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AmplitudSliderLabel = uilabel(app.LeftPanel);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.LeftPanel);
            app.AmplitudSlider.Limits = [0 25];
            app.AmplitudSlider.Value = 5;
            app.FrecuenciaSliderLabel = uilabel(app.LeftPanel);
            app.FrecuenciaSliderLabel.Visible = 'off';
            app.FrecuenciaSliderLabel.Text = 'Frecuencia';
            app.FrecuenciaSlider = uislider(app.LeftPanel);
            app.FrecuenciaSlider.Limits = [0 10];
            app.FrecuenciaSlider.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaSliderValueChanged, true);
            app.FrecuenciaSlider.Visible = 'off';
            app.FrecuenciaSlider.Value = 5;
            app.PeriodoscontinuaEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodoscontinuaEditFieldLabel.Visible = 'off';
            app.PeriodoscontinuaEditFieldLabel.Text = 'Periodos (continua)';
            app.PeriodoscontinuaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodoscontinuaEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodoscontinuaEditFieldValueChanged, true);
            app.PeriodoscontinuaEditField.Visible = 'off';
            app.PeriodoscontinuaEditField.Value = 5;
            app.PeriodosdiscretaEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodosdiscretaEditFieldLabel.Visible = 'off';
            app.PeriodosdiscretaEditFieldLabel.Text = 'Periodos (discreta)';
            app.PeriodosdiscretaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodosdiscretaEditField.Visible = 'off';
            app.PeriodosdiscretaEditField.Value = 5;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PeriodicaButton = uiradiobutton(app.ButtonGroup);
            app.PeriodicaButton.Text = 'Periodica';
            app.AperiodicaButton = uiradiobutton(app.ButtonGroup);
            app.AperiodicaButton.Text = 'Aperiodica';
            app.AperiodicaButton.Value = true;
            app.TazamuestreoEditFieldLabel = uilabel(app.LeftPanel);
            app.TazamuestreoEditFieldLabel.Text = 'Taza muestreo';
            app.TazamuestreoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TazamuestreoEditField.ValueChangedFcn = createCallbackFcn(app, @TazamuestreoEditFieldValueChanged, true);
            app.TazamuestreoEditField.Value = 1;
            app.RoEditFieldLabel = uilabel(app.LeftPanel);
            app.RoEditFieldLabel.Text = 'Ro';
            app.RoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RoEditField.ValueChangedFcn = createCallbackFcn(app, @RoEditFieldValueChanged, true);
            app.RoEditField.Value = -1;
            app.RfEditFieldLabel = uilabel(app.LeftPanel);
            app.RfEditFieldLabel.Text = 'Rf';
            app.RfEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RfEditField.ValueChangedFcn = createCallbackFcn(app, @RfEditFieldValueChanged, true);
            app.RfEditField.Value = 1;
            app.ParteSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ParteSwitch.Items = {'Real', 'Compleja'};
            app.ParteSwitch.ValueChangedFcn = createCallbackFcn(app, @ParteSwitchValueChanged, true);
            app.ParteSwitch.Visible = 'off';
            app.ParteSwitch.Value = 'Real';
            app.SealLabel = uilabel(app.LeftPanel);
            app.SealLabel.Text = 'Señal';
            app.DropDownSenales = uidropdown(app.LeftPanel);
            app.DropDownSenales.Items = {'Escalón unitario', 'Impulso unitario', 'Rampa', 'Senoidal', 'Senoidal compleja', 'Exponencial', 'Exponencial compleja'};
            app.DropDownSenales.DropDownOpeningFcn = createCallbackFcn(app, @DropDownSenalesOpening, true);
            app.DropDownSenales.ValueChangedFcn = createCallbackFcn(app, @DropDownSenalesValueChanged, true);
            app.DropDownSenales.Value = 'Escalón unitario';
            app.alphaSwitch = uiswitch(app.LeftPanel, 'slider');
            app.alphaSwitch.Items = {'Positivo', 'Negativo'};
            app.alphaSwitch.ValueChangedFcn = createCallbackFcn(app, @alphaSwitchValueChanged, true);
            app.alphaSwitch.Visible = 'off';
            app.alphaSwitch.Value = 'Positivo';
            app.DescomponercontinuaButton = uibutton(app.LeftPanel, 'push');
            app.DescomponercontinuaButton.ButtonPushedFcn = createCallbackFcn(app, @DescomponercontinuaButtonPushed, true);
            app.DescomponercontinuaButton.Text = 'Descomponer continua';
            app.DescomponerdiscretaButton = uibutton(app.LeftPanel, 'push');
            app.DescomponerdiscretaButton.ButtonPushedFcn = createCallbackFcn(app, @DescomponerdiscretaButtonPushed, true);
            app.DescomponerdiscretaButton.Text = 'Descomponer discreta';
            app.ButtonGenerar = uibutton(app.LeftPanel, 'push');
            app.ButtonGenerar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.ButtonGenerar.Text = 'Generar señal discreta y continua';
            app.PoEButton = uibutton(app.LeftPanel, 'push');
            app.PoEButton.ButtonPushedFcn = createCallbackFcn(app, @PoEButtonPushed, true);
            app.PoEButton.Text = '¿P o E?';
            app.Seal1Button = uibutton(app.LeftPanel, 'push');
            app.Seal1Button.ButtonPushedFcn = createCallbackFcn(app, @Seal1ButtonPushed, true);
            app.Seal1Button.Visible = 'off';
            app.Seal1Button.Text = 'Señal1';
            app.Seal2Button = uibutton(app.LeftPanel, 'push');
            app.Seal2Button.ButtonPushedFcn = createCallbackFcn(app, @Seal2ButtonPushed, true);
            app.Seal2Button.Visible = 'off';
            app.Seal2Button.Text = 'Señal2';
            app.ConvolucinLabel = uilabel(app.LeftPanel);
            app.ConvolucinLabel.Visible = 'off';
            app.ConvolucinLabel.Text = 'Convolución';
            app.TipodesealLabel = uilabel(app.LeftPanel);
            app.TipodesealLabel.Text = 'Tipo de señal';
            app.FuncionconvButton = uibutton(app.LeftPanel, 'push');
            app.FuncionconvButton.ButtonPushedFcn = createCallbackFcn(app, @FuncionconvButtonPushed, true);
            app.FuncionconvButton.Visible = 'off';
            app.FuncionconvButton.Text = 'Funcion conv';
            app.AlgoritmoconvButton = uibutton(app.LeftPanel, 'push');
            app.AlgoritmoconvButton.ButtonPushedFcn = createCallbackFcn(app, @AlgoritmoconvButtonPushed, true);
            app.AlgoritmoconvButton.Visible = 'off';
            app.AlgoritmoconvButton.Text = 'Algoritmo conv';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Visible = 'off';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Visible = 'off';
            app.TejecucionFuncLabel = uilabel(app.LeftPanel);
            app.TejecucionFuncLabel.Visible = 'off';
            app.TejecucionFuncLabel.Text = 'T ejecucion Func';
            app.TejecucionalgortimoLabel = uilabel(app.LeftPanel);
            app.TejecucionalgortimoLabel.Visible = 'off';
            app.TejecucionalgortimoLabel.Text = 'T ejecucion algortimo';
            app.CompararButton = uibutton(app.LeftPanel, 'push');
            app.CompararButton.ButtonPushedFcn = createCallbackFcn(app, @CompararButtonPushed, true);
            app.CompararButton.Visible = 'off';
            app.CompararButton.Text = 'Comparar';
            app.ModoButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroupSelectionChanged, true);
            app.ModoButtonGroup.Title = 'Modo';
            app.GeneradorButton = uiradiobutton(app.ModoButtonGroup);
            app.GeneradorButton.Text = 'Generador';
            app.GeneradorButton.Value = true;
            app.ConvolucionButton = uiradiobutton(app.ModoButtonGroup);
            app.ConvolucionButton.Text = 'Convolucion';
            app.CorrelacionButton = uiradiobutton(app.ModoButtonGroup);
            app.CorrelacionButton.Text = 'Correlacion';
            app.Generarseal1Button = uibutton(app.LeftPanel, 'push');
            app.Generarseal1Button.ButtonPushedFcn = createCallbackFcn(app, @Generarseal1ButtonPushed, true);
            app.Generarseal1Button.Visible = 'off';
            app.Generarseal1Button.Text = 'Generar señal 1';
            app.Generarseal2Button = uibutton(app.LeftPanel, 'push');
            app.Generarseal2Button.ButtonPushedFcn = createCallbackFcn(app, @Generarseal2ButtonPushed, true);
            app.Generarseal2Button.Visible = 'off';
            app.Generarseal2Button.Text = 'Generar  señal 2';
            app.CorrimientoEditFieldLabel = uilabel(app.LeftPanel);
            app.CorrimientoEditFieldLabel.Text = 'Corrimiento';
            app.CorrimientoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CorrelacinLabel = uilabel(app.LeftPanel);
            app.CorrelacinLabel.Visible = 'off';
            app.CorrelacinLabel.Text = 'Correlación';
            app.FuncionButton = uibutton(app.LeftPanel, 'push');
            app.FuncionButton.ButtonPushedFcn = createCallbackFcn(app, @FuncionButtonPushed, true);
            app.FuncionButton.Visible = 'off';
            app.FuncionButton.Text = 'Funcion';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.Visible = 'off';
            app.AlgoritmoButton = uibutton(app.LeftPanel, 'push');
            app.AlgoritmoButton.ButtonPushedFcn = createCallbackFcn(app, @AlgoritmoButtonPushed, true);
            app.AlgoritmoButton.Visible = 'off';
            app.AlgoritmoButton.Text = 'Algoritmo';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.OPERACIONESLabel = uilabel(app.RightPanel);
            app.OPERACIONESLabel.Text = 'OPERACIONES';
            app.RealizaroperacionsobrelavariableLabel = uilabel(app.RightPanel);
            app.RealizaroperacionsobrelavariableLabel.Text = 'Realizar operacion sobre la variable:';
            app.ButtonGroup_2 = uibuttongroup(app.RightPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.DependienteButton = uiradiobutton(app.ButtonGroup_2);
            app.DependienteButton.Text = 'Dependiente';
            app.DependienteButton.Value = true;
            app.IndependienteButton = uiradiobutton(app.ButtonGroup_2);
            app.IndependienteButton.Text = 'Independiente';
            app.TipodeoperacionLabel = uilabel(app.RightPanel);
            app.TipodeoperacionLabel.Visible = 'off';
            app.TipodeoperacionLabel.Text = 'Tipo de operacion';
            app.ButtonGroup_3 = uibuttongroup(app.RightPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ButtonGroup_3.Visible = 'off';
            app.EscalamientodetiempoButton = uiradiobutton(app.ButtonGroup_3);
            app.EscalamientodetiempoButton.Text = 'Escalamiento de tiempo';
            app.EscalamientodetiempoButton.Value = true;
            app.ReflexionButton = uiradiobutton(app.ButtonGroup_3);
            app.ReflexionButton.Text = 'Reflexion';
            app.CorrimientodetiempoButton = uiradiobutton(app.ButtonGroup_3);
            app.CorrimientodetiempoButton.Text = 'Corrimiento de tiempo';
            app.NumEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumEditField.ValueChangedFcn = createCallbackFcn(app, @NumEditFieldValueChanged, true);
            app.NumEditField.Visible = 'off';
            app.NumEditField.Value = 1;
            app.y1txLabel = uilabel(app.RightPanel);
            app.y1txLabel.Visible = 'off';
            app.y1txLabel.Text = 'y1(t)  =  x( ';
            app.tLabel = uilabel(app.RightPanel);
            app.tLabel.Visible = 'off';
            app.tLabel.Text = ' * t +';
            app.ButtonGroup_4 = uibuttongroup(app.RightPanel);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.ButtonGroup_4.Visible = 'off';
            app.ButtonGroup_4.SizeChangedFcn = createCallbackFcn(app, @ButtonGroup_4SizeChanged, true);
            app.ContinuaButton = uiradiobutton(app.ButtonGroup_4);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.DiscretaButton = uiradiobutton(app.ButtonGroup_4);
            app.DiscretaButton.Text = 'Discreta';
            app.OperarenlasealLabel = uilabel(app.RightPanel);
            app.OperarenlasealLabel.Visible = 'off';
            app.OperarenlasealLabel.Text = 'Operar en la señal:';
            app.ButtonGroup_5 = uibuttongroup(app.RightPanel);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.ButtonGroup_5.Visible = 'off';
            app.EscalamientodeamplitudButton = uiradiobutton(app.ButtonGroup_5);
            app.EscalamientodeamplitudButton.Text = 'Escalamiento de amplitud';
            app.EscalamientodeamplitudButton.Value = true;
            app.DiferenciacinButton = uiradiobutton(app.ButtonGroup_5);
            app.DiferenciacinButton.Text = 'Diferenciación';
            app.IntegracinButton = uiradiobutton(app.ButtonGroup_5);
            app.IntegracinButton.Text = 'Integración';
            app.SumaButton = uiradiobutton(app.ButtonGroup_5);
            app.SumaButton.Text = 'Suma';
            app.MultiplicacinButton = uiradiobutton(app.ButtonGroup_5);
            app.MultiplicacinButton.Text = 'Multiplicación';
            app.FactordeescalaEditFieldLabel = uilabel(app.RightPanel);
            app.FactordeescalaEditFieldLabel.Visible = 'off';
            app.FactordeescalaEditFieldLabel.Text = 'Factor de escala';
            app.FactordeescalaEditField = uieditfield(app.RightPanel, 'numeric');
            app.FactordeescalaEditField.ValueChangedFcn = createCallbackFcn(app, @FactordeescalaEditFieldValueChanged, true);
            app.FactordeescalaEditField.Visible = 'off';
            app.FactorcorrimientoEditField = uieditfield(app.RightPanel, 'numeric');
            app.FactorcorrimientoEditField.ValueChangedFcn = createCallbackFcn(app, @FactorcorrimientoEditFieldValueChanged, true);
            app.FactorcorrimientoEditField.Visible = 'off';
            app.Label = uilabel(app.RightPanel);
            app.Label.Visible = 'off';
            app.Label.Text = ')';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.AlgoritmoconvButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.ModoButtonGroup.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica811

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end