classdef u_Practica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CorteSuperiorSpinner        matlab.ui.control.Spinner
        CorteSuperiorSpinnerLabel   matlab.ui.control.Label
        CorteInferiorSpinner        matlab.ui.control.Spinner
        CorteInferiorSpinnerLabel   matlab.ui.control.Label
        FiltroDropDown              matlab.ui.control.DropDown
        FiltroDropDownLabel         matlab.ui.control.Label
        ButtonTransformadaFourier   matlab.ui.control.Button
        DivisionesSpinner           matlab.ui.control.Spinner
        DivisionesSpinnerLabel      matlab.ui.control.Label
        RepeticionesSpinner         matlab.ui.control.Spinner
        RepeticionesSpinnerLabel    matlab.ui.control.Label
        DesplazamientoSpinner       matlab.ui.control.Spinner
        DesplazamientoSpinnerLabel  matlab.ui.control.Label
        FrecuenciademuestreoporsegundoSpinner  matlab.ui.control.Spinner
        FrecuenciademuestreoporsegundoSpinnerLabel  matlab.ui.control.Label
        AmplitudSpinner             matlab.ui.control.Spinner
        AmplitudSpinnerLabel        matlab.ui.control.Label
        FrecuenciaSpinner           matlab.ui.control.Spinner
        FrecuenciaSpinnerLabel      matlab.ui.control.Label
        TiempodelasealLabel         matlab.ui.control.Label
        tfEditField                 matlab.ui.control.NumericEditField
        tfEditFieldLabel            matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        DropDownSenales             matlab.ui.control.DropDown
        SealLabel                   matlab.ui.control.Label
        ButtonGenerar               matlab.ui.control.Button
        UIAxes7                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signalPlot = []; % Para almacenar la última grafica generada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGenerar, UIAxes
        function ButtonGenerarPushed(app, event)
            f = app.FrecuenciaSpinner.Value; %Se obtiene la frecuencia del slider
            ampli = app.AmplitudSpinner.Value; %Se obtiene la amplitud del slider
            % Crear las señales continuas o discretas
            x = app.toEditField.Value:0.01:app.tfEditField.Value;
            xn = app.toEditField.Value:1/app.FrecuenciademuestreoporsegundoSpinner.Value:app.tfEditField.Value;

            switch app.DropDownSenales.Value

                case 'Pulso rectangular'
                    %Pulso rectangular continuo
                    y = ampli * (abs(x + app.DesplazamientoSpinner.Value) < f / 2);
                    %Pulso rectangular discreto
                    yn = ampli * (abs(xn + app.DesplazamientoSpinner.Value) < f / 2);

                case 'Escalón unitario'
                    % Definir la señal continua f(t)
                    y = ampli * (x + app.DesplazamientoSpinner.Value >= 0);
                    % Definir la señal discreta f[t]
                    yn = ampli * (xn + app.DesplazamientoSpinner.Value >= 0);

                case 'Impulso unitario'
                    y = ampli * (x + app.DesplazamientoSpinner.Value == 0);
                    yn = ampli * (xn + app.DesplazamientoSpinner.Value == 0);

                case 'Pulso triangular'
                    %Pulso triangular
                    y = (1 - abs(x + app.DesplazamientoSpinner.Value) / f) .* (abs(x + app.DesplazamientoSpinner.Value) <= f);
                    yn = (1 - abs(xn + app.DesplazamientoSpinner.Value) / f) .* (abs(xn + app.DesplazamientoSpinner.Value) <= f);

                case 'Rampa'
                    y = ampli * (x + app.DesplazamientoSpinner.Value) .* (x + app.DesplazamientoSpinner.Value >= 0);
                    yn = ampli * (xn + app.DesplazamientoSpinner.Value) .* (xn + app.DesplazamientoSpinner.Value >= 0);

                case 'Senoidal'
                    y = ampli * sin(2 * pi * f * (x + app.DesplazamientoSpinner.Value));
                    yn = ampli * sin(2 * pi * f * (xn + app.DesplazamientoSpinner.Value));

                case 'Senoidal compleja'
                    y = ampli * exp(1i * 2 * pi * f * (x + app.DesplazamientoSpinner.Value));
                    yn = ampli * exp(1i * 2 * pi * f * (xn + app.DesplazamientoSpinner.Value));

                case 'Exponencial'
                    y = ampli * exp(-(x + app.DesplazamientoSpinner.Value));
                    yn = ampli * exp(-(xn + app.DesplazamientoSpinner.Value));

                case 'Exponencial compleja'
                    y = ampli * exp(1i * 2 * pi * f * (x + app.DesplazamientoSpinner.Value)) .* exp(-(x + app.DesplazamientoSpinner.Value));
                    yn = ampli * exp(1i * 2 * pi * f * (xn + app.DesplazamientoSpinner.Value)) .* exp(-(xn + app.DesplazamientoSpinner.Value));

                case 'Señal en la naturaleza'
                    % Cargar la señal de audio
                    [audio, frecuenciaMuestreo] = audioread('minion_audio.wav');
                    t = (0:length(audio)-1) / frecuenciaMuestreo;
                    x = t;
                    y = audio;
                    xn = t;
                    yn = audio;
            end

            % Graficar ambas señales
            plot(app.UIAxes, x, y, 'b', 'LineWidth', 2);
            title(app.UIAxes, ['Señal Continua f(t) - ' app.DropDownSenales.Value]);
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud (m)');

            stem(app.UIAxes2, xn, yn, 'r', 'LineWidth', 2);
            title(app.UIAxes2, ['Señal Discreta f[t] - ' app.DropDownSenales.Value]);
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud (m)');

        end

        % Button pushed function: ButtonTransformadaFourier
        function ButtonTransformadaFourierPushed(app, event)

   % Obtener la señal seleccionada y sus parámetros
    y = app.UIAxes.Children.YData;
    frecuenciaMuestreo = app.FrecuenciademuestreoporsegundoSpinner.Value;
    
    if app.DropDownSenales.Value == 'Señal en la naturaleza'
        [audio, frecuenciaMuestreo] = audioread('minion_audio.wav');
    end

     % Normalización
    audio_normalizado = y / max(abs(y));

    % Añadimos ruido blanco
    ruido_blanco = 0.01 * randn(size(audio_normalizado));
    audio_con_ruido = audio_normalizado + ruido_blanco;

    % Completar con ceros para longitud de potencia de dos
    longitudPotenciaDos = 2 ^ nextpow2(length(audio_con_ruido));
    audio_completado = [audio_con_ruido, zeros(1, longitudPotenciaDos - length(audio_con_ruido))];

    % Aplicar la transformada rápida de Fourier a dicha señal
    transformadaManual = transformadaRapidaFourierManual(audio_completado);
    transformadaFFT = fft(audio_completado);

    % Comparación visual de los espectros de amplitud
    frecuencias = (0:longitudPotenciaDos - 1) * (frecuenciaMuestreo / longitudPotenciaDos);
    amplitudManual = abs(transformadaManual / longitudPotenciaDos);
    amplitudFFT = abs(transformadaFFT / longitudPotenciaDos);

    % Mostrar la FFT calculada manualmente
    plot(app.UIAxes3, frecuencias(1:longitudPotenciaDos/2), amplitudManual(1:longitudPotenciaDos/2));
    title(app.UIAxes3, 'Espectro de Amplitud de la FFT (manual)');
    xlabel(app.UIAxes3, 'Frecuencia (Hz)');
    ylabel(app.UIAxes3, 'Amplitud');

    % Mostrar la FFT calculada con MATLAB
    plot(app.UIAxes4, frecuencias(1:longitudPotenciaDos/2), amplitudFFT(1:longitudPotenciaDos/2));
    title(app.UIAxes4, 'Espectro de Amplitud de la FFT (MATLAB)');
    xlabel(app.UIAxes4, 'Frecuencia (Hz)');
    ylabel(app.UIAxes4, 'Amplitud');

    % Obtener selección del usuario para el tipo de filtro y los valores de corte
    tipoFiltro = app.FiltroDropDown.Value;
    corteInferior = app.CorteInferiorSpinner.Value;
    corteSuperior = app.CorteSuperiorSpinner.Value;

    switch tipoFiltro
        case 'Pasa Alta'
            if corteSuperior >= frecuenciaMuestreo / 2
                error('La frecuencia de corte superior debe ser menor que la mitad de la frecuencia de muestreo.');
            end
            [filtro, respuesta] = fir1(48, corteSuperior / (frecuenciaMuestreo / 2), 'high');
        case 'Pasa Baja'
            if corteInferior >= frecuenciaMuestreo / 2
                error('La frecuencia de corte inferior debe ser menor que la mitad de la frecuencia de muestreo.');
            end
            [filtro, respuesta] = fir1(48, corteInferior / (frecuenciaMuestreo / 2), 'low');
        case 'Pasa Banda'
            if corteInferior >= frecuenciaMuestreo / 2 || corteSuperior >= frecuenciaMuestreo / 2
                error('Las frecuencias de corte deben ser menores que la mitad de la frecuencia de muestreo.');
            end
            [filtro, respuesta] = fir1(48, [corteInferior, corteSuperior] / (frecuenciaMuestreo / 2), 'bandpass');
        case 'Rechaza Banda'
            if corteInferior >= frecuenciaMuestreo / 2 || corteSuperior >= frecuenciaMuestreo / 2
                error('Las frecuencias de corte deben ser menores que la mitad de la frecuencia de muestreo.');
            end
            [filtro, respuesta] = fir1(48, [corteInferior, corteSuperior] / (frecuenciaMuestreo / 2), 'stop');
    end

    audio_filtrado = filter(filtro, 1, audio_completado);

    % Visualizar graficamente la señal filtrada
    plot(app.UIAxes5, audio_filtrado);
    title(app.UIAxes5, 'Señal Filtrada');
    xlabel(app.UIAxes5, 'Tiempo');
    ylabel(app.UIAxes5, 'Amplitud');

    % Comparación en el dominio del tiempo
    plot(app.UIAxes6, audio_completado);
    hold(app.UIAxes6, 'on');
    plot(app.UIAxes6, audio_filtrado);
    title(app.UIAxes6, 'Comparacion en el dominio del tiempo: Original vs Filtrada');
    legend(app.UIAxes6, 'Original', 'Filtrada');
    xlabel(app.UIAxes6, 'Tiempo');
    ylabel(app.UIAxes6, 'Amplitud');
    hold(app.UIAxes6, 'off');

    % Punto 8: Visualizar gráficamente la comparación en el dominio de la frecuencia
    transformadaFiltrada = transformadaRapidaFourierManual(audio_filtrado);
    amplitudFiltrada = abs(transformadaFiltrada / longitudPotenciaDos);

    plot(app.UIAxes7, frecuencias(1:longitudPotenciaDos/2), amplitudFFT(1:longitudPotenciaDos/2));
    hold(app.UIAxes7, 'on');
    plot(app.UIAxes7, frecuencias(1:longitudPotenciaDos/2), amplitudFiltrada(1:longitudPotenciaDos/2));
    title(app.UIAxes7, 'Comparacion en el dominio de la frecuencia: Original vs Filtrada');
    legend(app.UIAxes7, 'Original', 'Filtrada');
    xlabel(app.UIAxes7, 'Frecuencia (Hz)');
    ylabel(app.UIAxes7, 'Amplitud');
    hold(app.UIAxes7, 'off');

% Funcion transformadaRapidaFourierManual para realizar la FFT manualmente
function X = transformadaRapidaFourierManual(x)
    % Obtenemos la longitud de la señal de entrada
    N = length(x);

    % Aseguramos que la longitud de la señal es una potencia de 2
    if mod(log2(N), 1) ~= 0
        error('La longitud de la señal debe ser una potencia de 2');
    end

    % Llamar a la funcion recursiva para calcular la FFT
    X = fftRecursiva(x);
end

function X = fftRecursiva(x)
    % Función recursiva para calcular la FFT

    % Obtener la longitud de la señal de entrada
    N = length(x);

    % Caso base: si N es 1, la FFT es la señal misma
    if N == 1
        X = x;
        return;
    end

    % Dividir la señal en partes par e impar
    x_pares = x(1:2:end); % Elementos pares
    x_impares = x(2:2:end); % Elementos impares

    % Calcular la FFT de las partes par e impar de forma recursiva
    X_pares = fftRecursiva(x_pares);
    X_impares = fftRecursiva(x_impares);

    % Prealocar el vector de salida
    X = zeros(N, 1);

    % Calcular los términos de la FFT usando la simetria
    for k = 0:(N / 2 - 1)
        % Calcular el factor de la exponencial compleja
        W_Nk = exp(-2i * pi * k / N);
        
        % Combinar las FFT de las partes par e impar
        X(k + 1) = X_pares(k + 1) + W_Nk * X_impares(k + 1);
        X(k + N / 2 + 1) = X_pares(k + 1) - W_Nk * X_impares(k + 1);
    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.ButtonGenerar = uibutton(app.UIFigure, 'push');
            app.ButtonGenerar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.ButtonGenerar.Text = 'Generar señal';
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Tipo de señal';
            app.DropDownSenales = uidropdown(app.UIFigure);
            app.DropDownSenales.Items = {'Escalón unitario', 'Impulso unitario', 'Rampa', 'Senoidal', 'Senoidal compleja', 'Exponencial', 'Exponencial compleja', 'Pulso triangular', 'Pulso rectangular', 'Señal en la naturaleza'};
            app.DropDownSenales.Value = 'Escalón unitario';
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.tfEditFieldLabel = uilabel(app.UIFigure);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.UIFigure, 'numeric');
            app.tfEditField.Value = 10;
            app.TiempodelasealLabel = uilabel(app.UIFigure);
            app.TiempodelasealLabel.Text = 'Tiempo de la señal';
            app.FrecuenciaSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciaSpinnerLabel.Text = 'Frecuencia';
            app.FrecuenciaSpinner = uispinner(app.UIFigure);
            app.FrecuenciaSpinner.Value = 1;
            app.AmplitudSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudSpinnerLabel.Text = 'Amplitud';
            app.AmplitudSpinner = uispinner(app.UIFigure);
            app.AmplitudSpinner.Value = 5;
            app.FrecuenciademuestreoporsegundoSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinnerLabel.Text = 'Frecuencia de muestreo por segundo';
            app.FrecuenciademuestreoporsegundoSpinner = uispinner(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinner.Step = 500;
            app.FrecuenciademuestreoporsegundoSpinner.Limits = [0 Inf];
            app.FrecuenciademuestreoporsegundoSpinner.Value = 5000;
            app.DesplazamientoSpinnerLabel = uilabel(app.UIFigure);
            app.DesplazamientoSpinnerLabel.Text = 'Desplazamiento';
            app.DesplazamientoSpinner = uispinner(app.UIFigure);
            app.RepeticionesSpinnerLabel = uilabel(app.UIFigure);
            app.RepeticionesSpinnerLabel.Text = 'Repeticiones';
            app.RepeticionesSpinner = uispinner(app.UIFigure);
            app.RepeticionesSpinner.Limits = [1 Inf];
            app.RepeticionesSpinner.Value = 1;
            app.DivisionesSpinnerLabel = uilabel(app.UIFigure);
            app.DivisionesSpinnerLabel.Text = 'Divisiones';
            app.DivisionesSpinner = uispinner(app.UIFigure);
            app.DivisionesSpinner.Limits = [1 Inf];
            app.DivisionesSpinner.Value = 1;
            app.ButtonTransformadaFourier = uibutton(app.UIFigure, 'push');
            app.ButtonTransformadaFourier.ButtonPushedFcn = createCallbackFcn(app, @ButtonTransformadaFourierPushed, true);
            app.ButtonTransformadaFourier.Text = 'FFT';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Pasa Alta', 'Pasa Baja', 'Pasa Banda', 'Rechaza Banda'};
            app.FiltroDropDown.Value = 'Pasa Alta';
            app.CorteInferiorSpinnerLabel = uilabel(app.UIFigure);
            app.CorteInferiorSpinnerLabel.Text = 'Corte Inferior';
            app.CorteInferiorSpinner = uispinner(app.UIFigure);
            app.CorteInferiorSpinner.Limits = [0 500];
            app.CorteSuperiorSpinnerLabel = uilabel(app.UIFigure);
            app.CorteSuperiorSpinnerLabel.Text = 'Corte Superior';
            app.CorteSuperiorSpinner = uispinner(app.UIFigure);
            app.CorteSuperiorSpinner.Limits = [0 2000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = u_Practica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end